classdef GUI_2024_3_22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout13                  matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        ConnectionsTab                matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        DropDown_12                   matlab.ui.control.DropDown
        LaserLabel_12                 matlab.ui.control.Label
        DropDown_11                   matlab.ui.control.DropDown
        DropDown_10                   matlab.ui.control.DropDown
        LaserLabel_11                 matlab.ui.control.Label
        LaserLabel_10                 matlab.ui.control.Label
        DropDown_9                    matlab.ui.control.DropDown
        LaserLabel_9                  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        DropDown_8                    matlab.ui.control.DropDown
        LaserLabel_8                  matlab.ui.control.Label
        TemperatureController1012040165EditField  matlab.ui.control.EditField
        TemperatureController1012040165EditFieldLabel  matlab.ui.control.Label
        DropDown_7                    matlab.ui.control.DropDown
        LaserLabel_7                  matlab.ui.control.Label
        DropDown_6                    matlab.ui.control.DropDown
        LaserLabel_6                  matlab.ui.control.Label
        DropDown_5                    matlab.ui.control.DropDown
        DropDown_4                    matlab.ui.control.DropDown
        DropDown_3                    matlab.ui.control.DropDown
        DropDown_2                    matlab.ui.control.DropDown
        DropDown_1                    matlab.ui.control.DropDown
        LaserLabel_5                  matlab.ui.control.Label
        LaserLabel_4                  matlab.ui.control.Label
        LaserLabel_3                  matlab.ui.control.Label
        LaserLabel_2                  matlab.ui.control.Label
        LaserLabel_1                  matlab.ui.control.Label
        ExperimentingTab              matlab.ui.container.Tab
        GridLayout9                   matlab.ui.container.GridLayout
        TempCEditFieldLabel_2         matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        Comms                         matlab.ui.container.Tab
        GridLayout11                  matlab.ui.container.GridLayout
        CommunicationsPanel           matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        Laser7Lamp                    matlab.ui.control.Lamp
        Laser7LampLabel               matlab.ui.control.Label
        Laser6Lamp                    matlab.ui.control.Lamp
        Laser6LampLabel               matlab.ui.control.Label
        LaserShuttersLamp             matlab.ui.control.Lamp
        LaserShuttersLampLabel        matlab.ui.control.Label
        TestCommunicationsButton      matlab.ui.control.Button
        ManipulatorLamp               matlab.ui.control.Lamp
        ManipulatorLampLabel          matlab.ui.control.Label
        SensorPLamp                   matlab.ui.control.Lamp
        SensorPLampLabel              matlab.ui.control.Label
        TemperatureLamp               matlab.ui.control.Lamp
        TemperatureLampLabel          matlab.ui.control.Label
        ShutterLamp                   matlab.ui.control.Lamp
        ShutterLampLabel              matlab.ui.control.Label
        LaserRailLamp                 matlab.ui.control.Lamp
        LaserRailLampLabel            matlab.ui.control.Label
        SensorTLamp                   matlab.ui.control.Lamp
        SensorTLampLabel              matlab.ui.control.Label
        SensorRLamp                   matlab.ui.control.Lamp
        SensorRLampLabel              matlab.ui.control.Label
        Laser5Lamp                    matlab.ui.control.Lamp
        Laser5LampLabel               matlab.ui.control.Label
        Laser4Lamp                    matlab.ui.control.Lamp
        Laser4LampLabel               matlab.ui.control.Label
        Laser3Lamp                    matlab.ui.control.Lamp
        Laser3LampLabel               matlab.ui.control.Label
        Laser1Lamp                    matlab.ui.control.Lamp
        Laser1LampLabel               matlab.ui.control.Label
        Laser2Lamp                    matlab.ui.control.Lamp
        Laser2LampLabel               matlab.ui.control.Label
        ActiveTestTab                 matlab.ui.container.Tab
        GridLayout12                  matlab.ui.container.GridLayout
        las7Label                     matlab.ui.control.Label
        las6Label                     matlab.ui.control.Label
        Growing1Lamp                  matlab.ui.control.Lamp
        Growing1LampLabel             matlab.ui.control.Label
        Measuring1Lamp                matlab.ui.control.Lamp
        Measuring1LampLabel           matlab.ui.control.Label
        TemperatureStablizing1Lamp    matlab.ui.control.Lamp
        TemperatureStablizing1LampLabel  matlab.ui.control.Label
        ManipulatorMovingLamp         matlab.ui.control.Lamp
        ManipulatorMovingLampLabel    matlab.ui.control.Label
        las5Label                     matlab.ui.control.Label
        las4Label                     matlab.ui.control.Label
        las3Label                     matlab.ui.control.Label
        las2Label                     matlab.ui.control.Label
        las1Label                     matlab.ui.control.Label
        LatestDataLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        Tab                           matlab.ui.container.Tab
        ActiveRunPanel                matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        ctestLabel                    matlab.ui.control.Label
        CurrentTestLabel              matlab.ui.control.Label
        crateLabel                    matlab.ui.control.Label
        CurrentRateCminLabel          matlab.ui.control.Label
        LasersActiveLamp              matlab.ui.control.Lamp
        LasersActiveLabel             matlab.ui.control.Label
        GrowingLamp                   matlab.ui.control.Lamp
        GrowingLabel                  matlab.ui.control.Label
        csubLabel                     matlab.ui.control.Label
        CurrentSubstrateLabel         matlab.ui.control.Label
        csetLabel                     matlab.ui.control.Label
        CurrentSetpointLabel          matlab.ui.control.Label
        ctemLabel                     matlab.ui.control.Label
        cdurLabel                     matlab.ui.control.Label
        ttemLabel                     matlab.ui.control.Label
        tdurLabel                     matlab.ui.control.Label
        CurrentDurationLabel          matlab.ui.control.Label
        CurrentTemperatureLabel       matlab.ui.control.Label
        TestTemperatureLabel          matlab.ui.control.Label
        TestDurationLabel             matlab.ui.control.Label
        FREEZEButton                  matlab.ui.control.Button
        STOPALLButton                 matlab.ui.control.Button
        TestConfigurationPanel        matlab.ui.container.Panel
        GridLayout18                  matlab.ui.container.GridLayout
        DurationsEditField            matlab.ui.control.NumericEditField
        DurationsEditFieldLabel       matlab.ui.control.Label
        TempCEditField                matlab.ui.control.NumericEditField
        TempCEditFieldLabel           matlab.ui.control.Label
        RunDesigningButton            matlab.ui.control.Button
        LoadDesigningButton           matlab.ui.control.Button
        DataCollectionCheckBox        matlab.ui.control.CheckBox
        ManipulatorMovementCheckBox   matlab.ui.control.CheckBox
        EffusionCellCheckBox          matlab.ui.control.CheckBox
        ModeButtonGroup               matlab.ui.container.ButtonGroup
        DesigningButton               matlab.ui.control.ToggleButton
        ProgrammedButton              matlab.ui.control.ToggleButton
        SingleButton                  matlab.ui.control.ToggleButton
        RunSingleButton               matlab.ui.control.Button
        RunSpreadsheetButton          matlab.ui.control.Button
        LoadSpreadsheetButton         matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        DevelopingTab                 matlab.ui.container.Tab
        GridLayout17                  matlab.ui.container.GridLayout
        LaserPanel_11                 matlab.ui.container.Panel
        LGrid_11                      matlab.ui.container.GridLayout
        OpenedLamp_6                  matlab.ui.control.Lamp
        OpenedLamp_6Label             matlab.ui.control.Label
        Switch_12                     matlab.ui.control.Switch
        LaserPanel_3                  matlab.ui.container.Panel
        LGrid_3                       matlab.ui.container.GridLayout
        wavLabel_3                    matlab.ui.control.Label
        LasingLamp_3                  matlab.ui.control.Lamp
        LasingLamp_3Label             matlab.ui.control.Label
        ConnectedLamp_3               matlab.ui.control.Lamp
        ConnectedLamp_3Label          matlab.ui.control.Label
        PowermWEditField_3            matlab.ui.control.NumericEditField
        PowermWEditField_3Label       matlab.ui.control.Label
        nmLabel_3                     matlab.ui.control.Label
        Switch_3                      matlab.ui.control.Switch
        ConnectButton_3               matlab.ui.control.Button
        LaserPanel_15                 matlab.ui.container.Panel
        LGrid_15                      matlab.ui.container.GridLayout
        OpenedLamp_9                  matlab.ui.control.Lamp
        OpenedLamp_9Label             matlab.ui.control.Label
        Switch_17                     matlab.ui.control.Switch
        LaserPanel_16                 matlab.ui.container.Panel
        LGrid_16                      matlab.ui.container.GridLayout
        wavLabel_7                    matlab.ui.control.Label
        LasingLamp_7                  matlab.ui.control.Lamp
        LasingLamp_7Label             matlab.ui.control.Label
        ConnectedLamp_13              matlab.ui.control.Lamp
        ConnectedLamp_13Label         matlab.ui.control.Label
        PowermWEditField_7            matlab.ui.control.NumericEditField
        PowermWEditField_7Label       matlab.ui.control.Label
        nmLabel_7                     matlab.ui.control.Label
        Switch_18                     matlab.ui.control.Switch
        ConnectButton_12              matlab.ui.control.Button
        LaserPanel_13                 matlab.ui.container.Panel
        LGrid_13                      matlab.ui.container.GridLayout
        OpenedLamp_8                  matlab.ui.control.Lamp
        OpenedLamp_8Label_2           matlab.ui.control.Label
        Switch_15                     matlab.ui.control.Switch
        LaserPanel_14                 matlab.ui.container.Panel
        LGrid_14                      matlab.ui.container.GridLayout
        wavLabel_6                    matlab.ui.control.Label
        LasingLamp_6                  matlab.ui.control.Lamp
        LasingLamp_6Label             matlab.ui.control.Label
        ConnectedLamp_12              matlab.ui.control.Lamp
        ConnectedLamp_12Label         matlab.ui.control.Label
        PowermWEditField_6            matlab.ui.control.NumericEditField
        PowermWEditField_6Label       matlab.ui.control.Label
        nmLabel_6                     matlab.ui.control.Label
        Switch_16                     matlab.ui.control.Switch
        ConnectButton_11              matlab.ui.control.Button
        LaserPanel_5                  matlab.ui.container.Panel
        LGrid_5                       matlab.ui.container.GridLayout
        wavLabel_5                    matlab.ui.control.Label
        LasingLamp_5                  matlab.ui.control.Lamp
        LasingLamp_5Label             matlab.ui.control.Label
        ConnectedLamp_5               matlab.ui.control.Lamp
        ConnectedLamp_5Label          matlab.ui.control.Label
        PowermWEditField_5            matlab.ui.control.NumericEditField
        PowermWEditField_5Label       matlab.ui.control.Label
        nmLabel_5                     matlab.ui.control.Label
        Switch_5                      matlab.ui.control.Switch
        ConnectButton_5               matlab.ui.control.Button
        LaserPanel_10                 matlab.ui.container.Panel
        LGrid_10                      matlab.ui.container.GridLayout
        OpenedLamp_5                  matlab.ui.control.Lamp
        OpenedLamp_5Label             matlab.ui.control.Label
        Switch_11                     matlab.ui.control.Switch
        LaserPanel_4                  matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        LGrid_4                       matlab.ui.container.GridLayout
        wavLabel_4                    matlab.ui.control.Label
        LasingLamp_4                  matlab.ui.control.Lamp
        LasingLamp_4Label             matlab.ui.control.Label
        ConnectedLamp_4               matlab.ui.control.Lamp
        ConnectedLamp_4Label          matlab.ui.control.Label
        PowermWEditField_4            matlab.ui.control.NumericEditField
        PowermWEditField_4Label       matlab.ui.control.Label
        nmLabel_4                     matlab.ui.control.Label
        Switch_4                      matlab.ui.control.Switch
        ConnectButton_4               matlab.ui.control.Button
        CryocoolerPanel               matlab.ui.container.Panel
        GridLayout15                  matlab.ui.container.GridLayout
        setKcurrentKLabel             matlab.ui.control.Label
        ReadTSetKButton               matlab.ui.control.Button
        Switch_14                     matlab.ui.control.Switch
        SetTemperatureEditField       matlab.ui.control.NumericEditField
        SetTemperatureEditFieldLabel  matlab.ui.control.Label
        tempKLabel                    matlab.ui.control.Label
        ReadTempPowerKWButton         matlab.ui.control.Button
        ConnectedLamp_11              matlab.ui.control.Lamp
        ConnectedLamp_11Label         matlab.ui.control.Label
        ConnectButton_10              matlab.ui.control.Button
        ConnectPM_4                   matlab.ui.control.Button
        LaserPanel_12                 matlab.ui.container.Panel
        LGrid_12                      matlab.ui.container.GridLayout
        OpenedLamp_7                  matlab.ui.control.Lamp
        OpenedLamp_7Label             matlab.ui.control.Label
        Switch_13                     matlab.ui.control.Switch
        LaserPanel_9                  matlab.ui.container.Panel
        LGrid_9                       matlab.ui.container.GridLayout
        OpenedLamp_4                  matlab.ui.control.Lamp
        OpenedLamp_4Label             matlab.ui.control.Label
        Switch_10                     matlab.ui.control.Switch
        LaserPanel_6                  matlab.ui.container.Panel
        LGrid_6                       matlab.ui.container.GridLayout
        OpenedLamp                    matlab.ui.control.Lamp
        OpenedLamp_8Label             matlab.ui.control.Label
        CloseAllButton                matlab.ui.control.Button
        ConnectedLamp_9               matlab.ui.control.Lamp
        ConnectedLamp_9Label          matlab.ui.control.Label
        Switch_7                      matlab.ui.control.Switch
        ConnectButton_9               matlab.ui.control.Button
        ManipulatorPanel              matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        targetLabel_2                 matlab.ui.control.Label
        GoToEditField                 matlab.ui.control.NumericEditField
        GoToEditFieldLabel            matlab.ui.control.Label
        UpdateButton_2                matlab.ui.control.Button
        cposLabel_2                   matlab.ui.control.Label
        PositionLabel                 matlab.ui.control.Label
        ConnectedLamp_8               matlab.ui.control.Lamp
        ConnectedLamp_8Label          matlab.ui.control.Label
        ConnectButton_8               matlab.ui.control.Button
        LaserPanel_2                  matlab.ui.container.Panel
        LGrid_2                       matlab.ui.container.GridLayout
        wavLabel_2                    matlab.ui.control.Label
        LasingLamp_2                  matlab.ui.control.Lamp
        LasingLamp_2Label             matlab.ui.control.Label
        ConnectedLamp_2               matlab.ui.control.Lamp
        ConnectedLamp_2Label          matlab.ui.control.Label
        PowermWEditField_2            matlab.ui.control.NumericEditField
        PowermWEditField_2Label       matlab.ui.control.Label
        nmLabel_2                     matlab.ui.control.Label
        Switch_2                      matlab.ui.control.Switch
        ConnectButton_2               matlab.ui.control.Button
        LaserPanel_1                  matlab.ui.container.Panel
        LGrid_1                       matlab.ui.container.GridLayout
        LasingLamp_1                  matlab.ui.control.Lamp
        ConnectedLamp_1               matlab.ui.control.Lamp
        wavLabel_1                    matlab.ui.control.Label
        LasingLampLabel_1             matlab.ui.control.Label
        ConnectedLampLabel_1          matlab.ui.control.Label
        PowermWEditField_1            matlab.ui.control.NumericEditField
        PowermWEditFieldLabel_1       matlab.ui.control.Label
        nmLabel_1                     matlab.ui.control.Label
        Switch_1                      matlab.ui.control.Switch
        ConnectButton_1               matlab.ui.control.Button
        TemperatureControllerPanel    matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        RampRateCminEditField         matlab.ui.control.NumericEditField
        RampRateCminEditFieldLabel    matlab.ui.control.Label
        GoToCEditField                matlab.ui.control.NumericEditField
        GoToCEditFieldLabel           matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.Button
        tempLabel                     matlab.ui.control.Label
        StableAtTargetLamp            matlab.ui.control.Lamp
        StableAtTargetLampLabel       matlab.ui.control.Label
        targetLabel                   matlab.ui.control.Label
        TempCLabel                    matlab.ui.control.Label
        ConnectedLamp_6               matlab.ui.control.Lamp
        ConnectedLamp_6Label          matlab.ui.control.Label
        ConnectButton_6               matlab.ui.control.Button
        ShutterPanel                  matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        ConnectedLamp_10              matlab.ui.control.Lamp
        Switch_6                      matlab.ui.control.Switch
        ConnectButton_7               matlab.ui.control.Button
        ConnectedLamp_7               matlab.ui.control.Lamp
        ConnectedLamp_7Label          matlab.ui.control.Label
        LaserRailPanel                matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        SetpointLabel                 matlab.ui.control.Label
        LRSwitch_2                    matlab.ui.control.Switch
        LRConnectedLamp_2             matlab.ui.control.Lamp
        LRConnectedLamp_2Label        matlab.ui.control.Label
        LRConnectButton_2             matlab.ui.control.Button
        LREditField                   matlab.ui.control.NumericEditField
        LRGoTommLabel                 matlab.ui.control.Label
        LRUpdateButton                matlab.ui.control.Button
        cposLabel                     matlab.ui.control.Label
        LRPositionmmLabel             matlab.ui.control.Label
        PowerMetersPanel              matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        CPL_3                         matlab.ui.control.Label
        CPL_2                         matlab.ui.control.Label
        CPL_1                         matlab.ui.control.Label
        CP_3                          matlab.ui.control.Label
        CP_2                          matlab.ui.control.Label
        CP_1                          matlab.ui.control.Label
        PM_Lamp_3                     matlab.ui.control.Lamp
        PM_Lamp_2                     matlab.ui.control.Lamp
        PM_Lamp_1                     matlab.ui.control.Lamp
        CollectDataLabel_1            matlab.ui.control.Label
        GOButton_1                    matlab.ui.control.Button
        GOButton_3                    matlab.ui.control.Button
        GOButton_2                    matlab.ui.control.Button
        SetnmEditField_3              matlab.ui.control.NumericEditField
        SetnmEditFieldLabel_3         matlab.ui.control.Label
        SetnmEditField_2              matlab.ui.control.NumericEditField
        SetnmEditFieldLabel_2         matlab.ui.control.Label
        ONButton_3                    matlab.ui.control.StateButton
        ONButton_2                    matlab.ui.control.StateButton
        SetRangeDropDown_3            matlab.ui.control.DropDown
        SetRangeDropDownLabel_3       matlab.ui.control.Label
        SetRangeDropDown_2            matlab.ui.control.DropDown
        SetRangeDropDownLabel_2       matlab.ui.control.Label
        SetnmEditField_1              matlab.ui.control.NumericEditField
        SetnmEditFieldLabel_1         matlab.ui.control.Label
        SetFilterLabel_1              matlab.ui.control.Label
        ONButton_1                    matlab.ui.control.StateButton
        SetRangeDropDown_1            matlab.ui.control.DropDown
        SetRangeDropDownLabel_1       matlab.ui.control.Label
        CollectDataLabel_3            matlab.ui.control.Label
        CollectDataLabel_2            matlab.ui.control.Label
        SetFilterLabel_3              matlab.ui.control.Label
        SetFilterLabel_2              matlab.ui.control.Label
        ConnectPM_3                   matlab.ui.control.Button
        ConnectPM_2                   matlab.ui.control.Button
        ConnectPM_1                   matlab.ui.control.Button
        PMLabel_3                     matlab.ui.control.Label
        PMLabel_2                     matlab.ui.control.Label
        PMLabel_1                     matlab.ui.control.Label
        SettingsTab                   matlab.ui.container.Tab
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    properties (Access = private)
        % For support - cjblake@uchicago.edu / theconnorblake@gmail.com /
        % 832-707-8311
        selected_file;
        selected_file_design;
        input_data;
        design_data;
        available_substrates = zeros(72,1);
        % physical parameters
        nlasers = 5; % How many lasers
        level_maxima = [30 24 18];
        substrate = 101;
        laser_wavelengths = [443 514 689 781 817 403 640]
        laser_positions = [0 42.5 85 127.5 170 170 170]; % positions of lasers on rail in mm
        thilo = [20 930]; % temperature range that can be input to temp PID
        rail_speed = 500; % mm/minute
        %This section handles the GUI names for displaying differing
        com_dict = dictionary(["Laser443","Laser514","Laser689","Laser781","Laser817","Laser403","Laser640","LaserRail","CellShutter","LaserShutters","Manipulator","CellTemp","CryoCooler"],["COM5","COM4","COM11","COM6","COM7","COM3","COM13","COM10","COM17","COM12","COM9","10.120.40.178","COM14"])
        tag_dict = dictionary(["Laser443","Laser514","Laser689","Laser781","Laser817","Laser403","Laser640","LaserRail","CellShutter","LaserShutters","Manipulator","CellTemp","CryoCooler","PowerMeter1","PowerMeter2","PowerMeter3"], ...
            ["laser_1", "laser_2", "laser_3", "laser_4", "laser_5", "laser_6", "laser_7","lr1","sh1","lash1","mn1","tp1","cryo","pm1","pm2","pm3"])
        comtag_dict = dictionary(["Laser443","Laser514","Laser689","Laser781","Laser817","Laser403","Laser640","LaserRail","CellShutter","LaserShutters","Manipulator","CellTemp","CryoCooler"], ...
                                  "l11","l21","l31","l41","l51","l61","l71","lr11","sh11","lash11","mn11","tp11","cryo1")
        hilist = ["AUTO","30W","3W","300mW","30mW","3mW","300uW","30uW"]; % names of options for power meters
        lolist = ["AUTO","30mW","3mW","300uW","30uW","3uW","300nW","30nW"];
        freeze_num = 0;
        % Core Communications
        lr, sh, pm, tp, mn, lash, cc; % object names for laser rail, shutter, power meters, temperature controller, manipulator, laser shutter, cryocooler
        laser_names = ["Laser443","Laser514","Laser689","Laser781","Laser817","Laser403","Laser640"];
        ld = configureDictionary("string","laser_driver") % object names for laser drivers
        init_dict = dictionary(["Laser443","Laser514","Laser689","Laser781","Laser817","Laser403","Laser640","LaserRail","CellShutter","LaserShutters","Manipulator","CellTemp","CryoCooler","PowerMeters"],zeros(1,14))
        
        substrate_num, stage_num, stage_inputs;
        data = []; % stores data in spreadsheet runs

        cOn = [0 1 0]; % green
        cOff = [1 0 0]; % red
        tstep = .5; % GUI refresh period

        not_active_testing = true;
        not_active_lasing = true;
        break_cycle = false;
        hold_setpoint = -1;
        hold_shutter = -1;
        frozen = false;

        programmed_filename = ""
        training_set = false;
        design_set = false;
        design_target_num = 0;
        design_sample_num = 0;
        % these are for live graphing
        tMem = [];
        yMem = [];
        zMem = [];
        graphN = 0;
        graphL = 0;
        graphT;
    end
    methods (Access = private)
        function col = lightIO(app,tf) % Just maps true and false to colors
            if tf
                col = app.cOn;
            else
                col = app.cOff;
            end
        end
        function disper = getDropDownList(~,~) % creates dropdown menu of accessible COM ports
            ccoms = serialportlist;
            disper = ccoms(2:end);  %kills COM1 which is useless on our computer
        end
        function m = next_substrate_num(app)
            lev = floor(app.substrate/100);
            if lev*100+app.level_maxima(lev) ~= app.substrate
                m = app.substrate+1;
            else
                if lev~=3
                    m = 100*(lev+1)+1;
                else
                    m = -1;
                end
            end
        end
        function go = system_confirm(app)
            good2go = app.updateExperimentingComs();
            app.laser_names = app.laser_names(1:app.nlasers);
            go = true;
            if ~good2go
                msg = 'Are you sure you want to run without all systems functioning?';
                title = 'Confirm Run';
                opts = {'Yes, run','No, do not run'};
                selection = uiconfirm(app.UIFigure,msg,title,'Options',opts,'DefaultOption',2,'CancelOption',2);
                if strcmp(selection,opts{2})
                    go = false;
                end
            end
        end
        function maybe = is_valid_index(~,num)
            firsta = num >= 101;
            firstb = num <= 130;
            seconda = num >= 201;
            secondb = num <= 224;
            thirda = num >= 301;
            thirdb = num <= 318;
            maybe = any([all([firsta firstb]) all([seconda secondb]) all([thirda thirdb])]);
        end
        function n = mindex2nindex(~,num)
            if num < 200
                n = num-100;
            elseif num < 300
                n = num-200+30;
            else
                n = num-300+54;
            end
        end
        function m = nindex2mindex(~,num)
            if num <= 30
                m = num + 100;
            elseif num <= 54
                m = num + 200 - 30;
            else
                m = num + 300 - 54;
            end
        end
        function nindex = next_available_substrate(app)
            cn = mindex2nindex(app,app.substrate);
            for i = cn:72
                if app.available_substrates(i)
                    nindex = i;
                    return;
                end
            end
            nindex = -1;
        end
        %reflection in a certain "clean substrate" range
        % function [results,meta] = run_cycle(app,temp, dur,run_data,run_cell,run_manip) %UPDATED 4/10
        %     % updates GUI to indicate active testing
        %     app.GrowingLamp.Color = app.cOff;
        %     app.LasersActiveLamp.Color = app.cOff;
        %     app.tdurLabel.Text = sprintf("%.1f",dur);
        %     app.ttemLabel.Text = sprintf("%.1f",temp);
        %     app.cdurLabel.Text = "IDLE";
        %     if run_cell
        %         app.csetLabel.Text = sprintf("%.1f",app.tp.getCurrentSetpoint());
        %     end
        %     if run_data
        %         %for i = 1:app.nlasers
        %          %   app.ld{i}.set_state(1,0);
        %         %end
        %         %for i = 1:app.nlasers %turn on all lasers, maybe shouldn't be here? Bill
        %         app.pm.set_filter_closed(3,true);
        %         app.pm.set_filter_closed(1,true);
        %         app.pm.set_filter_closed(2,true);
        %         app.pm.set_power_range(1,0);
        %         app.pm.set_power_range(2,0);
        %         app.pm.set_power_range(3,0);
        %     end
        %     ramprate = 15; % C/min at which warns
        %     pollrate = 10; % time steps to determine ramp rate by discrete derivative
        %     % SEND TO NEW TEMPERATURE
        %     if run_cell
        %         app.tp.setTemp(temp)
        %         tempdata = [app.tp.getTempC()];
        %         while ~app.tp.is_setpoint() %5 min seconds stable within +- .1 of target
        %             %store temp. data
        %             tic;
        %             app.tp.pushTempC();
        %             app.tp.updatePID();
        %             %update GUI
        %             ctem = app.tp.getTempC();
        %             cset = app.tp.getCurrentSetpoint();
        %             app.ctemLabel.Text = sprintf("%.1f",ctem);
        %             app.csetLabel.Text = sprintf("%.1f",cset);
        %             updateGraph(app,ctem,cset);
        %             tempdata(end+1) = app.tp.getTempC(); %#ok<AGROW> 
        %             % update the ramp rate every pollrate*tstep seconds, send
        %             % email if over
        %             if mod(numel(tempdata),pollrate) == 1 && numel(tempdata) ~= 1 % every pollrate time steps
        %                 tdiff = tempdata(end) - tempdata(end-pollrate);
        %                 rrate = tdiff/(pollrate*app.tstep/60);
        %                 app.crateLabel.Text = sprintf("%.1f",rrate);
        %                 %if abs(rrate) > ramprate
        %                  %   app.sendolmail(sprintf("Current Rate: %f",rrate),"Ramprate Overshot");
        %                 %end
        %             end
        %             % delay
        %             while app.frozen
        %                 pause(.05);
        %             end
        %             while toc < app.tstep
        %             end
        %         end
        %     end
        %     if run_manip
        %         while ~app.mn.there_yet % delay until manipulator in place
        %             while app.frozen
        %                 pause(.05);
        %             end
        %         end
        %     end
        %     % GROW SAMPLE
        %     if run_cell
        %         app.sh.open(); % open shutter
        %         app.GrowingLamp.Color = app.cOn;
        %         tic;
        %         tempdata = [app.tp.getTempC()];
        %         while toc < dur % wait until grown for set amount of time
        %             %update GUI while waiting for it to grow
        %             app.cdurLabel.Text = sprintf("%.1f",toc);
        %             ctem = app.tp.getTempC();
        %             cset = app.tp.getCurrentSetpoint();
        %             app.ctemLabel.Text = sprintf("%.1f",ctem);
        %             app.csetLabel.Text = sprintf("%.1f",cset);
        %             updateGraph(app,ctem,cset);
        %             pause(app.tstep);
        %             tempdata(end+1) = app.tp.getTempC(); %#ok<AGROW> % collect data during run for post analysis to ensure stability
        %             while app.frozen
        %                 pause(.05);
        %             end
        %         end
        % 
        %         app.cdurLabel.Text = "DONE";
        %         meta = [mean(tempdata) std(tempdata) dur];
        %         app.sh.close(); % close shutter
        %         app.GrowingLamp.Color = app.cOff;
        %     else
        %         meta = zeros(1,3);
        %     end
        %     % TAKE DATA
        %     if run_data
        %         app.not_active_lasing = false;
        %         [results,~] = collect_data(app); % fix 5x4 
        %         app.not_active_lasing = true;
        %     else
        %         results = zeros(app.nlasers,3);
        %     end
        % end
        function [res,time_points] = collect_data(app,pt_time)
            app.LasersActiveLamp.Color = app.cOn;
            res = zeros(app.nlasers,4);
            st = tic;
            time_points = zeros(app.nlasers,1);
            for i = app.laser_names % lasers all on 
                if app.init_dict(i)
                    app.ld(i).set_state(1,1);
                end
            end
            for icollect = 1:app.nlasers
                app.lr.moveAbsolute(app.laser_positions(icollect),app.rail_speed); % move ith laser into place
                wavl = app.ld(app.laser_names(icollect)).get_wav(1);
                %app.pm.set_wavelength(1,wavl);
                %app.pm.set_wavelength(2,wavl);
                %app.pm.set_wavelength(3,wavl);
                while abs(app.lr.getPos()-app.laser_positions(icollect)) > 0 % pauses program pointer until rail has reached target
                    while app.frozen
                        pause(.1);
                    end
                    app.cdurLabel.Text = sprintf("%.1f",pt_time);
                    % ctem = app.tp.getTempC();
                    % cset = app.tp.getCurrentSetpoint();
                    % app.ctemLabel.Text = sprintf("%.1f",ctem);
                    % app.csetLabel.Text = sprintf("%.1f",cset);
                    % updateGraph(app,ctem,cset);
                    pause(.5);
                end
                r_back = app.pm.read_power(2)*1000;
                app.lash.open(icollect);%open laser shutter icollect, tested it works
                app.cdurLabel.Text = sprintf("%.1f",pt_time);
                % ctem = app.tp.getTempC();
                % cset = app.tp.getCurrentSetpoint();
                % app.ctemLabel.Text = sprintf("%.1f",ctem);
                % app.csetLabel.Text = sprintf("%.1f",cset);
                % updateGraph(app,ctem,cset);
                pause(3)
                vals = app.pm.read_power_batch()*1000;
                if all(isnan(vals))
                    fprintf('Resetting Power Meters... Substrate: %d Time: %.1f \n',icollect,pt_time);
                    app.pm.set_wavelength(1,600);
                    app.pm.set_wavelength(2,600);
                    app.pm.set_wavelength(3,600);
                    pause(3);
                    vals = app.pm.read_power_batch()*1000;
                    if all(isnan(vals))
                        fatal_message = "Power Meter NAN";
                        fatal_body = fatal_message;
                        app.fatal_terminate(fatal_body,fatal_message,true);
                    end
                end
                p = vals(3);
                rr = vals(2);
                tt = vals(1);


                % p = app.pm.read_power(3)*1000; % reads split off power in mW
                % while app.frozen
                %     pause(.05);
                % end
                % rr = app.pm.read_power(2)*1000; % read reflectance (mW)
                % while app.frozen
                %     pause(.05);
                % end
                % tt = app.pm.read_power(1)*1000; % read transmittance (mW)
                % while app.frozen
                %     pause(.05);
                % end
                time_points(icollect) = toc(st);
                %show current values on display port
                if icollect == 1
                    app.las1Label.Text = sprintf("%.3f",rr/tt);
                elseif icollect == 2
                    app.las2Label.Text = sprintf("%.3f",rr/tt);
                elseif icollect == 3
                    app.las3Label.Text = sprintf("%.3f",rr/tt);
                elseif icollect == 4
                    app.las4Label.Text = sprintf("%.3f",rr/tt);
                elseif icollect == 5
                    app.las5Label.Text = sprintf("%.3f",rr/tt);
                elseif icollect == 6
                    app.las6Label.Text = sprintf("%.3f",rr/tt);
                elseif icollect == 7
                    app.las7Label.Text = sprintf("%.3f",rr/tt);
                end
                app.lash.close(icollect); % close laser shutter i
                res(icollect,:) = [tt rr p r_back]; % stores data
            end
            %app.lr.moveAbsolute(0,app.rail_speed); % move ith laser into place
            %while abs(app.lr.getPos()) > 0 % pauses program pointer until rail has reached target
                %pause(.1);
                %while app.frozen
                  %  pause(.05);
                %end
            %end
            %app.LasersActiveLamp.Color = app.cOff;
            %app.sendolmail(results,sprintf("Data for Cycle at %d C and %d seconds",temp,dur));
        end
        
        function RunTable(app,filename_in,CHECKTRANSMISSION)
            % runs all list of ordered pairs of times and temperatures and
            % writes to app.data
            if ~app.system_confirm()
                return;
            end
            app.ManipulatorMovingLamp.Color = app.cOff;
            app.TemperatureStablizing1Lamp.Color = app.cOff;
            app.Measuring1Lamp.Color = app.cOff;
            app.Growing1Lamp.Color = app.cOff;
            defaultColor = repmat([1, 1, 1], app.substrate_num, 1);
            set(app.UITable, 'BackgroundColor', defaultColor);

            app.TabGroup2.SelectedTab = app.ActiveTestTab;
            %initGraph(app);
            %app.mposLabel.Text = sprintf("%d",app.substrate);
            app.not_active_testing = false;
            for i = app.laser_names %turn on all lasers
                app.ld(i).set_state(1,1);
            end
            app.LasersActiveLamp.Color = app.cOn;

            % NEW DATA STORAGE
            headers = ["substrate" "T_cell" "T_sub" "time" "wavelength" "measurement (t,r,p)" "r_background" "mW"];
            out_table = [];


            fatal_message = "";
            fatal_body = "";
            try

                for rowIndex = 1:app.substrate_num % for each substrate
                    app.ctestLabel.Text = sprintf("%d",rowIndex);
                    looptic = tic;
                    %fprintf("Start Row   %.2f\n",toc(looptic));
                    defaultColor(rowIndex, :) = app.cOn;
                    set(app.UITable, 'BackgroundColor', defaultColor);
                    % Read the manipulator position from the first column
                    manipulator_position = app.input_data{rowIndex,1};
                    app.csubLabel.Text = sprintf("%d",manipulator_position);
                    temps = app.input_data{rowIndex,2:2:end};
                    timesi = app.input_data{rowIndex,3:2:end};
                    zeroIndex = find(timesi == 0, 1); % ragged stage numbers
                    if ~isempty(zeroIndex)
                        timesi = timesi(1:zeroIndex-1);
                        temps = temps(1:zeroIndex-1);
                    end
                    
                    app.pm.set_power_range(1,0);
                    app.pm.set_filter_closed(1,false);
                    app.pm.set_power_range(2,0);
                    app.pm.set_filter_closed(2,false);
                    app.pm.set_power_range(3,0);
                    app.pm.set_filter_closed(3,false);
                    % Move the manipulator to the specified position using mn.move_to()
                    app.ManipulatorMovingLamp.Color = app.cOn;
                    % fprintf("Move Manip (start)    %.2f\n",toc(looptic));
                    app.mn.move_to(manipulator_position);
                    
                    % Wait until the manipulator reaches the target position 
                    timeout = 60;  % Timeout in seconds
                    startTime = tic;  % Start the timer
                    while ~app.mn.there_yet && toc(startTime) < timeout
                        pause(0.5); 
                    end
                    if app.mn.there_yet
                        app.ManipulatorMovingLamp.Color = app.cOff;
                    else
                        writetable(array2table(out_table,"VariableNames",headers),"manip_aborted.xlsx")
                        fatal_message = "Manipulator Timeout";
                        fatal_body = fatal_message;
                        error("Manipulator")
                    end
                    % fprintf("Move Manip (end), Predata (start)    %.2f\n",toc(looptic));

                    % pre data
                    app.Measuring1Lamp.Color = app.cOn;
                    %measurement before growth
                    [res,~] = app.collect_data(0);
                    for j = 1:app.nlasers
                        for k = 1:3
                            datapoint = [app.input_data{rowIndex,1} 0 0 -1 app.laser_wavelengths(j) k res(j,4) res(j,k)];
                            out_table = vertcat(out_table,datapoint);
                        end
                    end
                    if CHECKTRANSMISSION
                        for j = 1:app.nlasers
                            tt_cutoff = 0.15;
                            if res(j,1) < tt_cutoff % if tt<0.3 probably the manipulator has problem
                                writetable(array2table(out_table,"VariableNames",headers),"tt_aborted.xlsx")
                                fatal_message = sprintf("tt (%.3f)<%.3fmW",res(j,1),tt_cutoff);
                                fatal_body = "";
                                error("Transmission")
                            end    
                        end
                    end
                    disp(out_table)
                    %fprintf("Predata (end)    %.2f\n",toc(looptic));

                    app.Measuring1Lamp.Color = app.cOff;
            
                    for i = 1:numel(temps)
                        %fprintf("Temp %d Start   %.2f\n",i,toc(looptic));
                        app.tp.setTemp(temps(i));
                        app.TemperatureStablizing1Lamp.Color = app.cOn;
                        app.ttemLabel.Text = sprintf("%.1f",temps(i));
                        app.tdurLabel.Text = sprintf("%.1f",timesi(i));
                        tempdata = [app.tp.getTempC()];
                        ramprate = 15; % C/min at which warns
                        pollrate = 10; % time steps to determine ramp rate by discrete derivative
                        pause(1);
                        while ~app.tp.is_setpoint() %5 min seconds stable within +- .1 of target
                            %store temp. data
                            steptic = tic;
                            app.tp.pushTempC();
                            app.tp.updatePID();
                            %update GUI
                            ctem = app.tp.getTempC();
                            % cset = app.tp.getCurrentSetpoint();
                            if ctem > 890
                                app.tp.setSetpointRate(2);
                            else
                                app.tp.setSetpointRate(5);
                            end
                            % app.ctemLabel.Text = sprintf("%.1f",ctem);
                            % app.csetLabel.Text = sprintf("%.1f",cset);
                            % updateGraph(app,ctem,cset);
                            tempdata(end+1) =ctem; %#ok<AGROW> 
                            % update the ramp rate every pollrate*tstep seconds, send
                            % email if over
                            if mod(numel(tempdata),pollrate) == 1 && numel(tempdata) ~= 1 % every pollrate time steps
                                tdiff = tempdata(end) - tempdata(end-pollrate);
                                rrate = tdiff/(pollrate*app.tstep/60);
                                app.crateLabel.Text = sprintf("%.1f",rrate);
                                %if abs(rrate) > ramprate
                                    %app.sendolmail(sprintf("Current Rate: %f",rrate),"Ramprate Overshot");
                                %end
                            end
                            % delay
                            while app.frozen
                                pause(.05);
                            end
                            %fprintf("Temp %d loop ramp %.1f  %.2f\n",i,ctem,toc(looptic));
                            pause(.5);
                        end
                        app.TemperatureStablizing1Lamp.Color = app.cOff;
                                                   
    
                        %grow
                        app.sh.open(); % open shutter
                        pause(0.2);
                        if ~app.sh.is_open()
                            writetable(array2table(out_table,"VariableNames",headers),"shutter_aborted.xlsx")
                            fatal_message = "Shutter Failure";
                            fatal_body = fatal_message;
                            error("Shutter")
                        end
    
                        app.Growing1Lamp.Color = app.cOn;
                                            
    
                        growtic = tic;
                        measurementTime = 120; % time to measure 1 cycle
                     
                        %fprintf("Temp %d grow start  %.2f\n",i,toc(looptic));
                        pause(.5);
                        while toc(growtic) < timesi(i)
                            %update GUI while waiting for it to grow
                            app.cdurLabel.Text = sprintf("%.1f",toc(growtic));
                            % ctem = app.tp.getTempC();
                            % cset = app.tp.getCurrentSetpoint();
                            % app.ctemLabel.Text = sprintf("%.1f",ctem);
                            % app.csetLabel.Text = sprintf("%.1f",cset);
                            % updateGraph(app,ctem,cset);
                            tempdata(end+1) = ctem; %#ok<AGROW> % collect data during run for post analysis to ensure stability
                            if toc(growtic) + measurementTime < timesi(i)
                                app.Measuring1Lamp.Color = app.cOn;
                                startmeasure = toc(growtic);
                                [res, tsps] = app.collect_data(toc(growtic));
                                for j = 1:app.nlasers
                                    for k = 1:3
                                        datapoint = [app.input_data{rowIndex,1} temps(i) 0 (startmeasure + tsps(j)) app.laser_wavelengths(j) k res(j,4) res(j,k)];
                                        out_table = vertcat(out_table,datapoint);
                                    end
                                end
                                disp(out_table)
                                writetable(array2table(out_table,"VariableNames",headers),filename_in);
                                app.Measuring1Lamp.Color = app.cOff;
    
                            end
                            while app.frozen
                                pause(.05);
                            end
                            %fprintf("Temp %d grow loop  %.1f %.2f\n",i,ctem,toc(looptic));
                            pause(.5);
                        end
                        %fprintf("Temp %d shutter close / start final   %.2f\n",i,toc(looptic));
                        app.sh.close(); 
                        app.Growing1Lamp.Color = app.cOff;
                        % final measurement after each stage, -1 time
                        app.Measuring1Lamp.Color = app.cOn;
                        [res, ~] = app.collect_data(timesi(i));
                        for j = 1:app.nlasers
                            for k = 1:3
                                datapoint = [app.input_data{rowIndex,1} temps(i) 0 timesi(i) app.laser_wavelengths(j) k res(j,4) res(j,k)];
                                out_table = vertcat(out_table,datapoint);
                            end
                        end
                        writetable(array2table(out_table,"VariableNames",headers),filename_in);
    
                        app.Measuring1Lamp.Color = app.cOff;
                        %fprintf("Temp %d end final measurement   %.2f\n",i,toc(looptic));
                    end
    
                    app.lr.moveAbsolute(0,app.rail_speed);
                    defaultColor(rowIndex, :) = [1, 1, 1];
                    set(app.UITable, 'BackgroundColor', defaultColor);
                end
                
                app.mn.move_to(101);
                app.tp.setTemp(600); %todo
                %for i = app.laser_names %turn off all lasers
                    %app.ld(i).set_state(1,0);
                %end      
                %after each sample
                try
                    app.sendolmail("Done: "+filename_in,"Done: "+filename_in);
                catch exception
                    disp(['Failed to send email for ', filename_in]);
                end
                app.not_active_testing = true;
            
            catch exception
                 if fatal_body == "" && fatal_message == ""
                    fatal_message = exception.identifier;
                    fatal_body = getReport(exception, 'extended', 'hyperlinks', 'off');
                end
                app.fatal_terminate(fatal_body,fatal_message,true);
            end           
        end
        function RunCalib(app,subi,fnamei)
            app.build_table(subi,875,1000,fnamei) % todo change
            app.selected_file = fnamei;
            app.load_in_file()
            app.RunTable("Output_"+fnamei,false)
        end
        function new_name = getSampleTarget(~,ii,typei)
            base_name = sprintf("sampletarget_Target%d.csv",typei); % pulls from folder
            sampleTargetData = readtable(base_name);
            new_name = sprintf("sampletarget_%d_Target%d.csv",ii,typei);
            writetable(sampleTargetData, new_name);
        end
        function load_in_file(app)
            app.input_data = readtable(app.selected_file);
            disp(app.input_data)
            input_dims = size(app.input_data);
            app.substrate_num = input_dims(1);
            % app.stage_num = (input_dims(2)-1)/2;
            % app.stage_inputs = app.input_data{1:end,2:end};
            % defaultColor = repmat([1, 1, 1], app.substrate_num, 1);
            % defaultColor(1, :) = app.cOn;
            app.UITable.Data = app.input_data;
            % app.UITable.ColumnName ={'Slot'; 'Temperature1 (C)'; 'Duration1 (s)'; 'Temperature2 (C)'; 'Duration2 (s)'; 'Temperature3 (C)'; 'Duration3 (s)';};
            % set(app.UITable, 'BackgroundColor', defaultColor);
            app.training_set = true;
        end
        function build_table(app,nextsubi,tempi,timei,fnameo)
            new_input_headers = ["Substrate" "TempC" "TimeS"];
            new_inputs = [nextsubi tempi timei];
            writetable(array2table(new_inputs,"VariableNames",new_input_headers),fnameo);
        end
        function timeout = get_time_for_temp(app,tempi)
            %timeout = 25;
            % timeout = 6000+100*(910-tempi);
            % timeout = exp((-tempi-298)*.020118)*1.75884*(1E14); % new 5/7
            timeout = 3.2163E14*exp(-2.8490E-2*tempi); % 5/17
        end
        function lossval = Loss(~,csvname,R,A)
            lossval = 0;
            coeffs = readtable(csvname);
            cwavR= coeffs.cWavR;
            cwavA = coeffs.cWavA;
            twavR = coeffs.tWavR;
            for i = 1:5
                lossval = lossval + cwavR(i)*(R(i)-twavR(i))^2 + cwavA(i)*(A(i))^2;
            end
        end
        function ready = updateExperimentingComs(app) %updates the colors on the experimenting tab to show what is connected, also part of warning system of running without all devices active
            goods = zeros(15,1);
            if app.init_dict("Laser443")
                try
                    if ~app.ld("Laser443").is_fault(1)
                        goods(1) = 1;
                        app.Laser1Lamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("Laser514")
                try
                    if ~app.ld("Laser514").is_fault(1)
                        goods(2) = 1;
                        app.Laser2Lamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("Laser689")
                try
                    if ~app.ld("Laser689").is_fault(1)
                        goods(3) = 1;
                        app.Laser3Lamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("Laser781")
                try
                    if ~app.ld("Laser781").is_fault(1)
                        goods(4) = 1;
                        app.Laser4Lamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("Laser817")
                try
                    if ~app.ld("Laser817").is_fault(1)
                        goods(5) = 1;
                        app.Laser5Lamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("Laser403")
                try
                    if ~app.ld("Laser403").is_fault(1)
                        goods(14) = 1;
                        app.Laser6Lamp.Color = app.cOn;
                    end
                catch
                end
            elseif app.nlasers < 7
                goods(14) = 1;
            end
            if app.init_dict("Laser640")
                try
                    if ~app.ld("Laser640").is_fault(1)
                        goods(15) = 1;
                        app.Laser7Lamp.Color = app.cOn;
                    end
                catch
                end
            elseif app.nlasers < 7
                goods(15) = 1;
            end
            if app.init_dict("PowerMeters")
                try
                    if app.pm.is_connected(1)
                        app.SensorTLamp.Color = app.cOn;
                        goods(6) = 1;
                    end
                catch
                end
                try
                    if app.pm.is_connected(2)
                        app.SensorRLamp.Color = app.cOn;
                        goods(7) = 1;
                    end
                catch
                end
                try
                    if app.pm.is_connected(3)
                        app.SensorPLamp.Color = app.cOn;
                        goods(8) = 1;
                    end
                catch
                end
            end
            if app.init_dict("LaserRail")
                try
                    if app.lr.test_com()
                        app.LaserRailLamp.Color = app.cOn;
                        goods(9) = 1;
                    end
                catch
                end
            end
            if app.init_dict("CellShutter")
                try
                    if app.sh.is_connected()
                        goods(10) = 1;
                        app.ShutterLamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("Manipulator")
                try
                    if app.mn.is_connected()
                        goods(11) = 1;
                        app.ManipulatorLamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("CellTemp")
                try
                    if app.tp.is_connected()
                        goods(12) = 1;
                        app.TemperatureLamp.Color = app.cOn;
                    end
                catch
                end
            end
            if app.init_dict("LaserShutters")
                try
                    if app.lash.is_connected()
                        goods(13) = 1;
                        app.LaserShuttersLamp.Color = app.cOn;
                    end
                catch
                end
            end
            ready = all(goods);
        end
        function sendolmail(~,msg,sbj) % sends emails to addresses via cmbe.yang@outlook.com logged in through the main computer, password is BZLMCB23
            %Sends email using MS Outlook
            addys = ["yuanlongzheng@uchicago.edu" "cjblake@uchicago.edu" "limravac@uchicago.edu"]; %#ok<NBRAK2>
            for i = 1:size(addys,2)
                h = actxserver('outlook.Application');
                mail = h.CreateItem('olMail');
                mail.Subject = sbj;
                mail.BodyFormat = 'olFormatHTML';
                mail.HTMLBody = msg;
                mail.To = addys(i);
                mail.Send;
                h.release;
            end
        end
        function initGraph(app) % sets up graph for live temperature graphing
            app.tMem = zeros(1,1);
            app.yMem = [app.tp.getTempC()];
            app.zMem = [app.tp.getCurrentSetpoint()];
            app.graphN = 1;
            app.graphL = 60*10/app.tstep; % 10 minutes if tstep = 1, maximum memory shown on graph
            app.graphT = tic;
        end
        function updateGraph(app,ct,csp)
            app.tMem(end+1) = toc(app.graphT);
            app.yMem(end+1) = ct;
            app.zMem(end+1) = csp;
            app.graphN = app.graphN + 1;
            if size(app.tMem,2) > app.graphL
                n1 = size(app.tMem,2);
                app.tMem = app.tMem(n1-app.graphL:n1);
                app.yMem = app.yMem(n1-app.graphL:n1);
                app.zMem = app.zMem(n1-app.graphL:n1);
            end
            sprintf("Push: %f %f\n", ct, csp);
            plot(app.tMem,app.yMem,"red",app.tMem,app.zMem,"green",'Parent',app.UIAxes);
            xlabel(app.UIAxes,"Time (s)");
            ylabel(app.UIAxes,"Temperature (C)");
            title(app.UIAxes,"Live Graphing");
            h = legend(app.UIAxes,"Thermocouple","Setpoint"); %#ok<NASGU> 
            ylim(app.UIAxes,[(min(min(app.yMem),min(app.zMem))-2) (max(max(app.yMem),max(app.zMem))+2)]);
        end
        function fatal_terminate(app,messageBody,messageSubject,manipReset)
            app.sendolmail(messageBody,messageSubject);
            for i = app.laser_names % lasers
                if app.init_dict(i)
                    app.ld(i).set_state(1,false);
                end
            end
            if manipReset
                app.mn.move_to(101);
            end
            if app.init_dict("LaserShutters") %laser shutter
                app.lash.closeAll();
            end
            if app.init_dict("CellShutter") % shutter
                app.sh.close();
            end
            if app.init_dict("LaserRail") % rail
                app.lr.moveAbsolute(0,app.rail_speed);
            end
            app.tp.setTemp(450);
            app.mn.move_to(101);
            pause(5);
            close all force
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            % Changes which buttons are displayed dependng on mode button
            % selection
            if app.ProgrammedButton.Value
                app.LoadSpreadsheetButton.Visible = true;
                app.RunSpreadsheetButton.Visible = true;
                app.RunDesigningButton.Visible = false;
                app.LoadDesigningButton.Visible = false;

                app.UITable.Visible = true;
                app.RunSingleButton.Visible = false;

                app.DurationsEditField.Visible = false;
                app.DurationsEditFieldLabel.Visible = false;
                app.TempCEditField.Visible = false;
                app.TempCEditFieldLabel.Visible = false;

                app.ManipulatorMovementCheckBox.Value = 1;
                app.ManipulatorMovementCheckBox.Enable = 0;
                app.EffusionCellCheckBox.Enable = 0;
                app.EffusionCellCheckBox.Value = 1;
            end
            if app.DesigningButton.Value
                app.LoadSpreadsheetButton.Visible = true;
                app.RunSpreadsheetButton.Visible = false;
                app.RunDesigningButton.Visible = true;
                app.LoadDesigningButton.Visible = true;

                app.UITable.Visible = true;
                app.RunSingleButton.Visible = false;

                app.DurationsEditField.Visible = false;
                app.DurationsEditFieldLabel.Visible = false;
                app.TempCEditField.Visible = false;
                app.TempCEditFieldLabel.Visible = false;

                app.ManipulatorMovementCheckBox.Value = 1;
                app.ManipulatorMovementCheckBox.Enable = 0;
                app.EffusionCellCheckBox.Enable = 0;
                app.EffusionCellCheckBox.Value = 1;          
            end
            if app.SingleButton.Value
                app.LoadSpreadsheetButton.Visible = false;
                app.RunSpreadsheetButton.Visible = false;
                app.RunDesigningButton.Visible = false;
                app.LoadDesigningButton.Visible = false;

                app.UITable.Visible = false;
                app.RunSingleButton.Visible = true;

                app.DurationsEditField.Visible = true;
                app.DurationsEditFieldLabel.Visible = true;
                app.TempCEditField.Visible = true;
                app.TempCEditFieldLabel.Visible = true;

                app.ManipulatorMovementCheckBox.Value = 0;
                app.ManipulatorMovementCheckBox.Enable = 1;
                app.EffusionCellCheckBox.Enable = 1;
                app.EffusionCellCheckBox.Value = 0;
            end
        end

        % Button pushed function: TestCommunicationsButton
        function TestCommunicationsButtonPushed(app, event)
            % changes colors of all lamps on experimenting page to
            % accurately reflect current state of devices
            app.LaserRailLamp.Color = [1 0 0];
            app.TemperatureLamp.Color = [1 0 0];
            app.SensorRLamp.Color = [1 0 0];
            app.SensorTLamp.Color = [1 0 0];
            app.ShutterLamp.Color = [1 0 0];
            app.Laser1Lamp.Color = [1 0 0];
            app.Laser2Lamp.Color = [1 0 0];
            app.Laser3Lamp.Color = [1 0 0];
            app.Laser4Lamp.Color = [1 0 0];
            app.Laser5Lamp.Color = [1 0 0];
            app.LaserShuttersLamp.Color = [1 0 0];
            app.updateExperimentingComs();
        end

        % Button pushed function: LoadSpreadsheetButton
        function LoadSpreadsheetButtonPushed(app, event)
            % temps is second column, times is third, manipulator position
            % is first

            app.selected_file = uigetfile('*.xlsx', 'Select an Excel file');
            % if class(app.selected_file) ==  'double'
            %     return
            % end
            app.load_in_file();
        end

        % Button pushed function: RunSpreadsheetButton
        function RunSpreadsheetButtonPushed(app, event)
            spreadsheet_filename = sprintf('data_group_datetime%s.csv',datestr(now,'mm-dd'));
            app.RunTable(spreadsheet_filename,false)
        end

        % Button pushed function: RunSingleButton
        function RunSingleButtonPushed(app, event)
            % runs a single test at given time and temperature
            if ~app.system_confirm
                return;
            end
            app.not_active_testing = false;
            app.TabGroup2.SelectedTab = app.ActiveTestTab;
            if app.EffusionCellCheckBox.Value
                %initGraph(app);
            end
            app.mposLabel.Text = sprintf("%d",app.substrate);
            temp = app.TempCEditField.Value;
            app.ctestLabel.Text = sprintf("%d",1);
            app.csubLabel.Text = sprintf("%d",app.substrate);
            dur = app.DurationsEditField.Value;
            [res, meta] = run_cycle(app,temp,dur,app.DataCollectionCheckBox.Value,app.EffusionCellCheckBox.Value,app.ManipulatorMovementCheckBox.Value);
            writematrix(res,sprintf('data_single_sub%d_temp%.1f_duration%.1f_datetime%s.csv',app.substrate,temp,dur,datestr(now,'mm-dd-HH.MM')));
            writematrix(meta,sprintf('meta_single_sub%d_temp%.1f_duration%.1f_datetime%s.csv',app.substrate,temp,dur,datestr(now,'mm-dd-HH.MM')));
            app.not_active_testing = true;
        end

        % Button down function: ExperimentingTab
        function ExperimentingTabButtonDown(app, event)

        end

        % Button down function: DevelopingTab
        function DevelopingTabButtonDown(app, event)
            % makes sure can only interact with the devices that have been activated
            % disable/enable based on testing
            for i = keys(app.comtag_dict)
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict(i)),'enable',app.not_active_testing);
            end
            for i = keys(app.tag_dict)
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict(i)),'enable',app.not_active_testing);
            end
            % hide based on initialization
            if ~app.init_dict("Laser443")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser443")),'visible',0);
                app.ConnectedLamp_1.Color = app.cOff;
            end
            if ~app.init_dict("Laser514")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser514")),'visible',0);
                app.ConnectedLamp_2.Color = app.cOff;
            end
            if ~app.init_dict("Laser689")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser689")),'visible',0);
                app.ConnectedLamp_3.Color = app.cOff;
            end
            if ~app.init_dict("Laser781")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser781")),'visible',0);
                app.ConnectedLamp_4.Color = app.cOff;
            end
            if ~app.init_dict("Laser817")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser817")),'visible',0);
                app.ConnectedLamp_5.Color = app.cOff;
            end
            if ~app.init_dict("Laser403")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser403")),'visible',0);
                app.ConnectedLamp_12.Color = app.cOff;
            end
            if ~app.init_dict("Laser640")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser640")),'visible',0);
                app.ConnectedLamp_13.Color = app.cOff;
            end
            if ~app.init_dict("PowerMeters")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter1")),'visible',0);
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter2")),'visible',0);
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter3")),'visible',0);
                app.PM_Lamp_1.Color = app.cOff;
                app.PM_Lamp_2.Color = app.cOff;
                app.PM_Lamp_3.Color = app.cOff;
            end
            if ~app.init_dict("LaserRail")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("LaserRail")),'visible',0);
                app.LRConnectedLamp_2.Color = app.cOff;
            end
            if ~app.init_dict("CellShutter")
                % shutter
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("CellShutter")),'visible',0);
                app.ConnectedLamp_7.Color = app.cOff;
            end
            if ~app.init_dict("Manipulator")
                % manipulator
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Manipulator")),'visible',0);
                app.ConnectedLamp_8.Color = app.cOff;
            end
            if ~app.init_dict("CellTemp")
                % eurotherm
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("CellTemp")),'visible',0);
                app.ConnectedLamp_6.Color = app.cOff;
            end
            if ~app.init_dict("LaserShutters")
                % laser shutter
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("LaserShutters")),'visible',0);
                app.ConnectedLamp_9.Color = app.cOff;
            end
            if ~app.init_dict("CryoCooler")
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("CryoCooler")),'visible',0);
                app.ConnectedLamp_11.Color = app.cOff;
            end
        end

        % Value changed function: Switch_1
        function Switch_1ValueChanged(app, event)
            % turns laser 1 on / off
            value = app.Switch_1.Value;
            value = contains(value,'On');
            if ~app.ld("Laser443").is_fault(1)
                app.ld("Laser443").set_state(1,value);
                app.LasingLamp_1.Color = app.lightIO(value);
            end
        end

        % Button pushed function: ConnectButton_1
        function ConnectButton_1Pushed(app, event)
            % setup laser 1
            if ~app.init_dict("Laser443")
                app.ld("Laser443") = laser_driver(app.com_dict("Laser443"));
                if ~app.ld("Laser443").is_fault(1)
                    app.init_dict("Laser443") = true;
                    set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser443")),'visible',1);
                    app.LasingLamp_1.Color = app.cOff;
                    app.ConnectedLamp_1.Color = app.cOn;
                    app.Switch_1.Value = 'Off';
                    wavnm = app.ld("Laser443").get_wav(1);
                    app.wavLabel_1.Text = sprintf("%d",wavnm);

                    if ~app.ld("Laser443").is_conwav(1)
                        app.PowermWEditFieldLabel_1.Visible = false;
                        app.PowermWEditField_1.Visible = false;
                    end

                    app.DropDown_1.Enable = false;
                else
                    app.ld("Laser443").terminate();
                end
            end
        end

        % Value changed function: PowermWEditField_1
        function PowermWEditField_1ValueChanged(app, event)
            % set power of laser 1
            value = app.PowermWEditField_1.Value;
            if app.ld("Laser443").is_fault(1)
                return;
            end
            if app.ld("Laser443").is_conwav(1)
                app.ld("Laser443").set_power(1,.001*value);
            end
        end

        % Button pushed function: ConnectPM_1
        function ConnectPM_1ButtonPushed(app, event)
            % connect power meter 1,2,3
            if ~app.init_dict("PowerMeters")
                app.pm = power_meters();
                app.init_dict("PowerMeters") = true;
            else
                app.pm.rescan();
            end
            if app.pm.is_connected(1)
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter1")),'visible',1);
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter1")),'enable',1);
                app.PM_Lamp_1.Color = app.cOn;
                app.pm.set_wavelength(1,600);
                app.pm.set_power_range(1,0);
                app.pm.set_filter_closed(1,false);
                app.ONButton_1.Value = false;
                if app.pm.is_filter_closed(1)
                    app.SetRangeDropDown_1.Items = app.hilist;
                else
                    app.SetRangeDropDown_1.Items = app.lolist;
                end
                app.ONButton_1.Value = false;
                app.ONButton_1.Text = "OPENED";
                app.SetnmEditField_1.Value = 600;
                app.SetnmEditField_1.Limits = [400 1100];
            end
            if app.pm.is_connected(2)
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter2")),'visible',1);
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter2")),'enable',1);
                app.PM_Lamp_2.Color = app.cOn;
                app.pm.set_wavelength(2,600);
                app.pm.set_power_range(2,0);
                app.pm.set_filter_closed(2,false);
                app.ONButton_2.Value = false;
                if app.pm.is_filter_closed(2)
                    app.SetRangeDropDown_2.Items = app.hilist;
                else
                    app.SetRangeDropDown_2.Items = app.lolist;
                end
                app.ONButton_2.Value = false;
                app.ONButton_2.Text = "OPENED";
                app.SetnmEditField_2.Value = 600;
                app.SetnmEditField_2.Limits = [400 1100];
            end
            if app.pm.is_connected(3)
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter3")),'visible',1);
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("PowerMeter3")),'enable',1);
                app.PM_Lamp_3.Color = app.cOn;
                app.pm.set_wavelength(3,600);
                app.pm.set_power_range(3,0);
                app.pm.set_filter_closed(3,false);
                app.ONButton_3.Value = false;
                if app.pm.is_filter_closed(3)
                    app.SetRangeDropDown_3.Items = app.hilist;
                else
                    app.SetRangeDropDown_3.Items = app.lolist;
                end
                app.ONButton_3.Value = false;
                app.ONButton_3.Text = "OPENED";
                app.SetnmEditField_3.Value = 600;
                app.SetnmEditField_3.Limits = [400 1100];
            end
        end

        % Button pushed function: ConnectPM_2
        function ConnectPM_2ButtonPushed(app, event)
            % connect power meter 1,2,3
            app.ConnectPM_1ButtonPushed;
        end

        % Button pushed function: ConnectPM_3
        function ConnectPM_3ButtonPushed(app, event)
            % connect power meter 1,2,3
            app.ConnectPM_1ButtonPushed;
        end

        % Value changed function: SetRangeDropDown_1
        function SetRangeDropDown_1ValueChanged(app, event)
            value = app.SetRangeDropDown_1.Value;
            if app.pm.is_filter_closed(1)
                thislist = app.hilist;
            else
                thislist = app.lolist;
            end
            app.pm.set_power_range(1,find(thislist==value)-1);
            app.pm.set_wavelength(1,app.SetnmEditField_1.Value);
        end

        % Drop down opening function: SetRangeDropDown_1
        function SetRangeDropDown_1Opening(app, event)
            if app.pm.is_filter_closed(1)
                app.SetRangeDropDown_1.Items = app.hilist;
            else
                app.SetRangeDropDown_1.Items = app.lolist;
            end
        end

        % Value changed function: ONButton_1
        function ONButton_1ValueChanged(app, event)
            value = app.ONButton_1.Value;
            if value
                app.pm.set_filter_closed(1,true);
                app.ONButton_1.Text = "CLOSED";
                app.SetRangeDropDown_1.Items = app.lolist;
                app.SetRangeDropDown_1.Value = app.lolist(1);
            else
                app.pm.set_filter_closed(1,false);
                app.ONButton_1.Text = "OPEN";
                app.SetRangeDropDown_1.Items = app.hilist;
                app.SetRangeDropDown_1.Value = app.hilist(1);
            end
            app.pm.set_power_range(1,1); % auto
            app.pm.set_wavelength(1,app.SetnmEditField_1.Value);
        end

        % Value changed function: SetnmEditField_1
        function SetnmEditField_1ValueChanged(app, event)
            value = app.SetnmEditField_1.Value;
            app.pm.set_wavelength(1,value);
        end

        % Button pushed function: GOButton_1
        function GOButton_1Pushed(app, event)
            cp = app.pm.read_power(1);
            app.CP_1.Text = sprintf("%f",cp);
        end

        % Button pushed function: LRConnectButton_2
        function LRConnectButton_2Pushed(app, event)
            if ~app.init_dict("LaserRail")
                app.lr = laser_rail(app.com_dict("LaserRail"));
                app.init_dict("LaserRail") = true;
            end
            if app.lr.test_com()
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("LaserRail")),'visible',1);
                app.LRConnectedLamp_2.Color = app.cOn;
                app.LREditField.Limits = [-170 300];
                cpos = app.lr.getPos();
                app.cposLabel.Text = sprintf("%.3f",cpos);
                app.SetpointLabel.Text = sprintf("Presets: %.1f %.1f %.1f %.1f %.1f %.1f %.1f",app.laser_positions(1),app.laser_positions(2),app.laser_positions(3),app.laser_positions(4),app.laser_positions(5),app.laser_positions(6),app.laser_positions(7));
                app.LREditField.Value = cpos(1);
                app.LRSwitch_2.Value = 'Moving';
                app.DropDown_6.Enable = false;
            end
        end

        % Button pushed function: LRUpdateButton
        function LRUpdateButtonPushed(app, event)
            cpos = app.lr.getPos();
            app.cposLabel.Text = sprintf("%.3f",cpos(1));
            app.LREditField.Value = cpos(1);
            pause(.3); % to prevent glitches from reading too quickly
        end

        % Value changed function: LREditField
        function LREditFieldValueChanged(app, event)
            value = app.LREditField.Value;
            app.lr.moveAbsolute(value,app.rail_speed);
        end

        % Value changed function: LRSwitch_2
        function LRSwitch_2ValueChanged(app, event)
            value = app.LRSwitch_2.Value;
            if contains(value,'Stop')
                app.lr.freeze();
            else
                app.lr.unfreeze();
            end
        end

        % Drop down opening function: DropDown_1
        function DropDown_1Opening(app, event)
            app.DropDown_1.Items = getDropDownList(app,1);
        end

        % Value changed function: DropDown_1
        function DropDown_1ValueChanged(app, event)

        end

        % Clicked callback: DropDown_1
        function DropDown_1Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Laser443") = sprintf("%s",app.DropDown_1.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Laser443")),'visible',1);
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            % turns laser 2 on / off
            value = app.Switch_2.Value;
            value = contains(value,'On');
            if ~app.ld("Laser514").is_fault(1)
                app.ld("Laser514").set_state(1,value);
                app.LasingLamp_2.Color = app.lightIO(value);
            end
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            % turns laser 3 on / off
            value = app.Switch_3.Value;
            value = contains(value,'On');
            if ~app.ld("Laser689").is_fault(1)
                app.ld("Laser689").set_state(1,value);
                app.LasingLamp_3.Color = app.lightIO(value);
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            % turns laser 4 on / off
            value = app.Switch_4.Value;
            value = contains(value,'On');
            if ~app.ld("Laser781").is_fault(1)
                app.ld("Laser781").set_state(1,value);
                app.LasingLamp_4.Color = app.lightIO(value);
            end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            % turns laser 5 on / off
            value = app.Switch_5.Value;
            value = contains(value,'On');
            if ~app.ld("Laser817").is_fault(1)
                app.ld("Laser817").set_state(1,value);
                app.LasingLamp_5.Color = app.lightIO(value);
            end
        end

        % Value changed function: PowermWEditField_2
        function PowermWEditField_2ValueChanged(app, event)
            % set power of laser 2
            value = app.PowermWEditField_2.Value;
            if app.ld("Laser514").is_fault(1)
                return;
            end
            if app.ld("Laser514").is_conwav(1)
                app.ld("Laser514").set_power(1,.001*value);
            end
        end

        % Value changed function: PowermWEditField_3
        function PowermWEditField_3ValueChanged(app, event)
            % set power of laser 3
            value = app.PowermWEditField_3.Value;
            if app.ld("Laser689").is_fault(1)
                return;
            end
            if app.ld("Laser689").is_conwav(1)
                app.ld("Laser689").set_power(1,.001*value);
            end
        end

        % Value changed function: PowermWEditField_4
        function PowermWEditField_4ValueChanged(app, event)
            % set power of laser 4
            value = app.PowermWEditField_4.Value;
            if app.ld("Laser781").is_fault(1)
                return;
            end
            if app.ld("Laser781").is_conwav(1)
                app.ld("Laser781").set_power(1,.001*value);
            end
        end

        % Value changed function: PowermWEditField_5
        function PowermWEditField_5ValueChanged(app, event)
            % set power of laser 5
            value = app.PowermWEditField_5.Value;
            if app.ld("Laser817").is_fault(1)
                return;
            end
            if app.ld("Laser817").is_conwav(1)
                app.ld("Laser817").set_power(1,.001*value);
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            DropDown_1ValueChanged(app,event);
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            DropDown_1ValueChanged(app,event);
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            DropDown_1ValueChanged(app,event);
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            DropDown_1ValueChanged(app,event);
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            DropDown_1ValueChanged(app,event);
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            DropDown_1ValueChanged(app,event);
        end

        % Drop down opening function: DropDown_2
        function DropDown_2Opening(app, event)
            app.DropDown_2.Items = getDropDownList(app,2);
        end

        % Drop down opening function: DropDown_3
        function DropDown_3Opening(app, event)
            app.DropDown_3.Items = getDropDownList(app,3);
        end

        % Drop down opening function: DropDown_4
        function DropDown_4Opening(app, event)
            app.DropDown_4.Items = getDropDownList(app,4);
        end

        % Drop down opening function: DropDown_5
        function DropDown_5Opening(app, event)
            app.DropDown_5.Items = getDropDownList(app,5);
        end

        % Drop down opening function: DropDown_6
        function DropDown_6Opening(app, event)
            app.DropDown_6.Items = getDropDownList(app,6);
            
        end

        % Drop down opening function: DropDown_7
        function DropDown_7Opening(app, event)
            app.DropDown_7.Items = getDropDownList(app,7);
        end

        % Clicked callback: DropDown_2
        function DropDown_2Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Laser514") = sprintf("%s",app.DropDown_2.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Laser514")),'visible',1);
            end
        end

        % Clicked callback: DropDown_3
        function DropDown_3Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Laser689") = sprintf("%s",app.DropDown_3.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Laser689")),'visible',1);
            end
        end

        % Clicked callback: DropDown_4
        function DropDown_4Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Laser781") = sprintf("%s",app.DropDown_4.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Laser781")),'visible',1);
            end
        end

        % Clicked callback: DropDown_5
        function DropDown_5Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Laser817") = sprintf("%s",app.DropDown_5.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Laser817")),'visible',1);
            end
        end

        % Clicked callback: DropDown_6
        function DropDown_6Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("LaserRail") = sprintf("%s",app.DropDown_6.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("LaserRail")),'visible',1);
            end
        end

        % Clicked callback: DropDown_7
        function DropDown_7Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("CellShutter") = sprintf("%s",app.DropDown_7.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("CellShutter")),'visible',1);
            end
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            % setup laser 2
            if ~app.init_dict("Laser514")
                app.ld("Laser514") = laser_driver(app.com_dict("Laser514"));
                if ~app.ld("Laser514").is_fault(1)
                    app.init_dict("Laser514") = true;
                    set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser514")),'visible',1);
                    app.LasingLamp_2.Color = app.cOff;
                    app.ConnectedLamp_2.Color = app.cOn;
                    app.Switch_2.Value = 'Off';
                    wavnm = app.ld("Laser514").get_wav(1);
                    app.wavLabel_2.Text = sprintf("%d",wavnm);
                    
                    if ~app.ld("Laser514").is_conwav(1)
                        app.PowermWEditField_2Label.Visible = false;
                        app.PowermWEditField_2.Visible = false;
                    end

                    app.DropDown_2.Enable = false;
                else
                    app.ld("Laser514").terminate();
                end
            end
        end

        % Button pushed function: ConnectButton_3
        function ConnectButton_3Pushed(app, event)
            % setup laser 3
            if ~app.init_dict("Laser689")
                app.ld("Laser689") = laser_driver(app.com_dict("Laser689"));
                if ~app.ld("Laser689").is_fault(1)
                    app.init_dict("Laser689") = true;
                    set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser689")),'visible',1);
                    app.LasingLamp_3.Color = app.cOff;
                    app.ConnectedLamp_3.Color = app.cOn;
                    app.Switch_3.Value = 'Off';
                    wavnm = app.ld("Laser689").get_wav(1);
                    app.wavLabel_3.Text = sprintf("%d",wavnm);

                    if ~app.ld("Laser689").is_conwav(1)
                        app.PowermWEditField_3Label.Visible = false;
                        app.PowermWEditField_3.Visible = false;
                    end
                    app.DropDown_3.Enable = false;
                else
                    app.ld("Laser689").terminate();
                end
            end
        end

        % Button pushed function: ConnectButton_4
        function ConnectButton_4Pushed(app, event)
            % setup laser 4
            if ~app.init_dict("Laser781")
                app.ld("Laser781") = laser_driver(app.com_dict("Laser781"));
                if ~app.ld("Laser781").is_fault(1)
                    app.init_dict("Laser781") = true;
                    set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser781")),'visible',1);
                    app.LasingLamp_4.Color = app.cOff;
                    app.ConnectedLamp_4.Color = app.cOn;
                    app.Switch_4.Value = 'Off';
                    wavnm = app.ld("Laser781").get_wav(1);
                    app.wavLabel_4.Text = sprintf("%d",wavnm);

                    if ~app.ld("Laser781").is_conwav(1)
                        app.PowermWEditField_4Label.Visible = false;
                        app.PowermWEditField_4.Visible = false;
                    end
                    app.DropDown_4.Enable = false;
                else
                    app.ld("Laser781").terminate();
                end
            end
        end

        % Button pushed function: ConnectButton_5
        function ConnectButton_5Pushed(app, event)
            % setup laser 5
            if ~app.init_dict("Laser817")
                app.ld("Laser817") = laser_driver(app.com_dict("Laser817"));
                if ~app.ld("Laser817").is_fault(1)
                    app.init_dict("Laser817") = true;
                    set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser817")),'visible',1);
                    app.LasingLamp_5.Color = app.cOff;
                    app.ConnectedLamp_5.Color = app.cOn;
                    app.Switch_5.Value = 'Off';
                    wavnm = app.ld("Laser817").get_wav(1);
                    app.wavLabel_5.Text = sprintf("%d",wavnm);

                   if ~app.ld("Laser817").is_conwav(1)
                        app.PowermWEditField_5Label.Visible = false;
                        app.PowermWEditField_5.Visible = false;
                    end
                    app.DropDown_5.Enable = false;
                else
                    app.ld("Laser817").terminate();
                end
            end
        end

        % Value changed function: SetRangeDropDown_2
        function SetRangeDropDown_2ValueChanged(app, event)
            value = app.SetRangeDropDown_2.Value;
            if app.pm.is_filter_closed(2)
                thislist = app.hilist;
            else
                thislist = app.lolist;
            end
            app.pm.set_power_range(2,find(thislist==value)-1);
            app.pm.set_wavelength(2,app.SetnmEditField_2.Value);
        end

        % Drop down opening function: SetRangeDropDown_2
        function SetRangeDropDown_2Opening(app, event)
            if app.pm.is_filter_closed(2)
                app.SetRangeDropDown_2.Items = app.hilist;
            else
                app.SetRangeDropDown_2.Items = app.lolist;
            end
        end

        % Value changed function: SetRangeDropDown_3
        function SetRangeDropDown_3ValueChanged(app, event)
            value = app.SetRangeDropDown_3.Value;
            if app.pm.is_filter_closed(3)
                thislist = app.hilist;
            else
                thislist = app.lolist;
            end
            app.pm.set_power_range(3,find(thislist==value)-1);
            app.pm.set_wavelength(3,app.SetnmEditField_3.Value);
            
        end

        % Drop down opening function: SetRangeDropDown_3
        function SetRangeDropDown_3Opening(app, event)
            if app.pm.is_filter_closed(3)
                app.SetRangeDropDown_3.Items = app.hilist;
            else
                app.SetRangeDropDown_3.Items = app.lolist;
            end
        end

        % Value changed function: ONButton_2
        function ONButton_2ValueChanged(app, event)
            value = app.ONButton_2.Value;
            if value
                app.pm.set_filter_closed(2,false);
                app.ONButton_2.Text = "CLOSED";
                app.SetRangeDropDown_2.Items = app.lolist;
                app.SetRangeDropDown_2.Value = app.lolist(1);
            else
                app.pm.set_filter_closed(2,false);
                app.ONButton_2.Text = "OPEN";
                app.SetRangeDropDown_2.Items = app.hilist;
                app.SetRangeDropDown_2.Value = app.hilist(1);
            end
            app.pm.set_power_range(2,1); % auto
            app.pm.set_wavelength(2,app.SetnmEditField_2.Value);
        end

        % Value changed function: ONButton_3
        function ONButton_3ValueChanged(app, event)
            value = app.ONButton_3.Value;
            if value
                app.pm.set_filter_closed(3,false);
                app.ONButton_3.Text = "CLOSED";
                app.SetRangeDropDown_3.Items = app.lolist;
                app.SetRangeDropDown_3.Value = app.lolist(1);
            else
                app.pm.set_filter_closed(3,false);
                app.ONButton_3.Text = "OPEN";
                app.SetRangeDropDown_3.Items = app.hilist;
                app.SetRangeDropDown_3.Value = app.hilist(1);
            end
            app.pm.set_power_range(3,1); % auto
            app.pm.set_wavelength(3,app.SetnmEditField_3.Value);
            
        end

        % Value changed function: SetnmEditField_2
        function SetnmEditField_2ValueChanged(app, event)
            value = app.SetnmEditField_2.Value;
            app.pm.set_wavelength(2,value);
        end

        % Value changed function: SetnmEditField_3
        function SetnmEditField_3ValueChanged(app, event)
            value = app.SetnmEditField_3.Value;
            app.pm.set_wavelength(3,value);
        end

        % Button pushed function: GOButton_2
        function GOButton_2Pushed(app, event)
            cp = app.pm.read_power(2);
            app.CP_2.Text = sprintf("%f",cp);
        end

        % Button pushed function: GOButton_3
        function GOButton_3Pushed(app, event)
            cp = app.pm.read_power(3);
            app.CP_3.Text = sprintf("%f",cp);
        end

        % Button pushed function: ConnectButton_6
        function ConnectButton_6Pushed(app, event)
            %Eurotherm
            if ~app.init_dict("CellTemp")
                app.tp = temp_pid(app.com_dict("CellTemp"));
            end
            if app.tp.is_connected()
                app.init_dict("CellTemp") = true;
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("CellTemp")),'visible',1);
                app.ConnectedLamp_6.Color = app.cOn;
                app.GoToCEditField.Limits = [20 950];
                ctem = app.tp.getTempC();
                app.targetLabel.Text = sprintf("%.1f",app.tp.getCurrentSetpoint());
                app.tempLabel.Text = sprintf("%.1f",ctem);
                app.GoToCEditField.Value = ctem;
                app.StableAtTargetLamp.Color = app.cOff;
                app.TemperatureController1012040165EditField.Enable = false;
            end
        end

        % Value changed function: TemperatureController1012040165EditField
        function TemperatureController1012040165EditFieldValueChanged(app, event)
            value = app.TemperatureController1012040165EditField.Value;
            if ~isempty(value)
                app.com_dict("CellTemp") = sprintf("%s",value);
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("CellTemp")),'visible',1);
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.tempLabel.Text = sprintf("%.1f",app.tp.getTempC());
            app.targetLabel.Text = sprintf("%.1f",app.tp.getCurrentSetpoint());
            app.tp.updatePID();
            if app.tp.is_setpoint()
                app.StableAtTargetLamp.Color = app.cOn;
            else
                app.StableAtTargetLamp.Color = app.cOff;
            end
        end

        % Value changed function: GoToCEditField
        function GoToCEditFieldValueChanged(app, event)
            value = max(app.thilo(1),min(app.thilo(2),app.GoToCEditField.Value));
            
            app.tp.setTemp(value);
            app.targetLabel.Text = sprintf("%.1f",value);
            app.tp.updatePID();
            if app.tp.is_setpoint()
                app.StableAtTargetLamp.Color = app.cOn;
            else
                app.StableAtTargetLamp.Color = app.cOff;
            end
        end

        % Button pushed function: ConnectButton_7
        function ConnectButton_7Pushed(app, event)
            %Shutter
            if ~app.init_dict("CellShutter")
                app.sh = shutter(app.com_dict("CellShutter"));
                app.init_dict("CellShutter") = true;
            end
            if app.sh.is_connected()
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("CellShutter")),'visible',1);
                app.ConnectedLamp_7.Color = app.cOn;
                statehold = ""; %#ok<NASGU> 
                if app.sh.is_open()
                    statehold = 'Open';
                else
                    statehold = 'Close';
                end
                app.Switch_6.Value = statehold;
                app.DropDown_7.Enable = false;
                app.ConnectedLamp_10.Color = app.cOff;
            end
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            value = app.Switch_6.Value;
            % NOTE: does NOT check if manipulator is moving
            if contains(value,'Open')
                app.sh.open();
                pause(0.2);
                if app.sh.is_open()
                    app.ConnectedLamp_10.Color = app.cOn;
                else
                    app.ConnectedLamp_10.Color = app.cOff;
                end
            else
                app.sh.close();
                pause(0.2);
                if app.sh.is_open()
                    app.ConnectedLamp_10.Color = app.cOn;
                else
                    app.ConnectedLamp_10.Color = app.cOff;
                end
            end
        end

        % Button pushed function: ConnectButton_8
        function ConnectButton_8Pushed(app, event)
            %Manipulator
            if ~app.init_dict("Manipulator")
                app.mn = manipy(app.com_dict("Manipulator"));
                app.mn.initialize();
                app.init_dict("Manipulator") = true;
            end
            if app.mn.is_connected()
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Manipulator")),'visible',1);
                app.ConnectedLamp_8.Color = app.cOn;
                app.GoToEditField.Limits = [0 319];
                app.GoToEditField.RoundFractionalValues = true;
                %app.available_substrates(app.mindex2nindex(301)) = 1;
                app.available_substrates(app.mindex2nindex(303)) = 1;
                %app.available_substrates(app.mindex2nindex(305)) = 1;
                app.available_substrates(app.mindex2nindex(307)) = 1;
                %app.available_substrates(app.mindex2nindex(309)) = 1;
                %app.available_substrates(app.mindex2nindex(313)) = 1;
                %app.available_substrates(app.mindex2nindex(315)) = 1;
                %app.available_substrates(app.fgemindex2nindex(317)) = 1;
                
                app.mn.move_to(app.substrate);
                pos = app.substrate;
                if ~app.mn.there_yet()
                    app.cposLabel_2.Text = "Moving";
                else
                    app.cposLabel_2.Text = sprintf("%d",app.substrate);
                    app.GoToCEditField.Value = pos;
                end
                app.DropDown_8.Enable = false;
            end
        end

        % Drop down opening function: DropDown_8
        function DropDown_8Opening(app, event)
            app.DropDown_8.Items = getDropDownList(app,8);
        end

        % Clicked callback: DropDown_8
        function DropDown_8Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Manipulator") = sprintf("%s",app.DropDown_8.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Manipulator")),'visible',1);
            end
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            if ~app.mn.there_yet
                app.cposLabel_2.Text = "Moving";
            else
                app.cposLabel_2.Text = sprintf("%d",app.substrate);
            end
        end

        % Value changed function: GoToEditField
        function GoToEditFieldValueChanged(app, event)
            value = app.GoToEditField.Value;
            if app.is_valid_index(value) && app.mn.there_yet
                app.mn.move_to(value);
                app.substrate = value;
                app.targetLabel_2.Text = sprintf("%d",value);
            else
                app.targetLabel_2.Text = "INVALID";
            end
        end

        % Button pushed function: FREEZEButton
        function FREEZEButtonPushed(app, event)
            if app.not_active_testing
                return;
            end
            if app.frozen == false % freeze
                app.FREEZEButton.Text = "UNFREEZE";
                app.frozen = true;
                app.break_cycle = true;
                if app.init_dict("CellShutter") % shutter
                    app.hold_shutter = app.sh.is_open();
                    app.sh.close();
                end
                if app.init_dict("CellTemp") % temp
                    app.hold_setpoint = app.tp.getFinalSetpoint();
                    app.tp.setTemp(app.tp.getTempC());
                    app.tp.updatePID();
                end
                for i = app.laser_names
                    if app.init_dict(i)
                        app.ld(i).set_state(1,false);
                    end
                end
                if app.init_dict("LaserRail") % rail
                    app.lr.freeze();
                end
                if app.init_dict("LaserShutters") %laser shutter
                    app.lash.closeAll();
                end
            else
                app.frozen = false;
                app.FREEZEButton.Text = "FREEZE";
                app.break_cycle = false;
                if app.init_dict("CellShutter") % shutter
                    if app.hold_shutter
                        app.sh.open();
                    else
                        app.sh.close();
                    end
                end
                if app.init_dict("CellTemp") % temp
                    app.tp.setTemp(app.hold_setpoint);
                end
                if app.init_dict("LaserRail") % rail
                    app.lr.unfreeze();
                end
                if ~app.not_active_lasing % lasers
                    [res,~] = app.collect_data(-1);
                    writematrix(res,sprintf("frozen_error_%d.csv",app.freeze_num));
                    app.freeze_num = app.freeze_num + 1;
                end
            end
        end

        % Callback function
        function DropDown_9Opening(app, event)
        app.DropDown_9.Items = getDropDownList(app,9);
        end

        % Value changing function: TemperatureController1012040165EditField
        function TemperatureController1012040165EditFieldValueChanging(app, event)
            %changingValue = event.Value;
        end

        % Button pushed function: STOPALLButton
        function STOPALLButtonPushed(app, event)
            writematrix(app.data,sprintf('aborted_data_datetime%s.csv',datestr(now,'mm-dd-HH.MM')));
            app.fatal_terminate("","Manual Stop",true);
        end

        % Callback function: CloseAllButton, DataCollectionCheckBox
        function CloseAllButtonPushed(app, event)
            
            app.lash.closeAll();
            app.Switch_7.Value = 'Close';
            app.Switch_10.Value = 'Close';
            app.Switch_11.Value = 'Close';
            app.Switch_12.Value = 'Close';
            app.Switch_13.Value = 'Close';
            app.OpenedLamp.Color = app.cOff;
            app.OpenedLamp_4.Color = app.cOff;
            app.OpenedLamp_5.Color = app.cOff;
            app.OpenedLamp_6.Color = app.cOff;
            app.OpenedLamp_7.Color = app.cOff;
        end

        % Value changed function: DropDown_9
        function DropDown_9ValueChanged(app, event)
            value = app.DropDown_9.Value;
        end

        % Value changed function: DropDown_8
        function DropDown_8ValueChanged(app, event)
            value = app.DropDown_8.Value;
        end

        % Drop down opening function: DropDown_9
        function DropDown_9Opening2(app, event)
            app.DropDown_9.Items = getDropDownList(app,9);
        end

        % Clicked callback: DropDown_9
        function DropDown_9Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("LaserShutters") = sprintf("%s",app.DropDown_9.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("LaserShutters")),'visible',1);
            end
        end

        % Button pushed function: ConnectButton_9
        function ConnectButton_9Pushed(app, event)
            if ~app.init_dict("LaserShutters")
                app.lash = laser_shutter(app.com_dict("LaserShutters"));
                app.init_dict("LaserShutters") = true;
            end
            if app.lash.is_connected()
                pause(3); %need to do this because everytime when first sending a signal the servo move to random angles
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("LaserShutters")),'visible',1);
                app.ConnectedLamp_9.Color = app.cOn;
                %statehold = ""; %#ok<NASGU> 
                %if app.sh.is_open()
                 %   statehold = 'Open';
                %else
                 %   statehold = 'Closed';
                %end
                %app.Switch_6.Value = statehold;
                app.DropDown_9.Enable = false;
                app.lash.closeAll(); 
                app.OpenedLamp.Color = app.cOff;
                app.OpenedLamp_4.Color = app.cOff;
                app.OpenedLamp_5.Color = app.cOff;
                app.OpenedLamp_6.Color = app.cOff;
                app.OpenedLamp_7.Color = app.cOff;
            end
            
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            %app.lash = laser_shutter(app.com_dict("LaserShutters"));  
            value = app.Switch_7.Value;
            if contains(value,'Open')
                app.lash.open(1);
                app.OpenedLamp.Color = app.cOn;
            else
                app.lash.close(1);
                app.OpenedLamp.Color = app.cOff;
            end
        end

        % Value changed function: Switch_13
        function Switch_13ValueChanged(app, event)
            %app.lash = laser_shutter(app.com_dict("LaserShutters"));
            value = app.Switch_13.Value;
            if contains(value, 'Open')
                app.lash.open(2);
                app.OpenedLamp_7.Color = app.cOn;
            else
                app.lash.close(2);
                app.OpenedLamp_7.Color = app.cOff;
            end
        end

        % Value changed function: Switch_12
        function Switch_12ValueChanged(app, event)
            %app.lash = laser_shutter(app.com_dict("LaserShutters"));
            value = app.Switch_12.Value;
            if contains(value,'Open')
                app.lash.open(3);
                app.OpenedLamp_6.Color = app.cOn;
            else
                app.lash.close(3);
                app.OpenedLamp_6.Color = app.cOff;
            end
        end

        % Value changed function: Switch_10
        function Switch_10ValueChanged(app, event)
            %app.lash = laser_shutter(app.com_dict("LaserShutters"));
            value = app.Switch_10.Value;
            if contains(value,'Open')
                app.lash.open(4);
                app.OpenedLamp_4.Color = app.cOn;
            else
                app.lash.close(4);
                app.OpenedLamp_4.Color = app.cOff;
            end
        end

        % Value changed function: Switch_11
        function Switch_11ValueChanged(app, event)
            %app.lash = laser_shutter(app.com_dict("LaserShutters"));
            value = app.Switch_11.Value;
            if contains(value,'Open')
                app.lash.open(5);
                app.OpenedLamp_5.Color = app.cOn;
            else
                app.lash.close(5);
                app.OpenedLamp_5.Color = app.cOff;
            end
        end

        % Value changed function: RampRateCminEditField
        function RampRateCminEditFieldValueChanged(app, event)
            value = app.RampRateCminEditField.Value;
            app.tp.setSetpointRate(value);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Button pushed function: ConnectPM_4
        function ConnectPM_4ButtonPushed(app, event)
            ConnectButton_1Pushed(app,1);
            ConnectButton_2Pushed(app,1);
            ConnectButton_3Pushed(app,1);
            ConnectButton_4Pushed(app,1);
            ConnectButton_5Pushed(app,1);
            ConnectButton_9Pushed(app,1);
            ConnectButton_7Pushed(app,1);
            ConnectButton_6Pushed(app,1);
            ConnectButton_8Pushed(app,1);
            % ConnectButton_10Pushed(app,1); % doesn't auto activate
            % cryostat
            if app.nlasers == 7
                ConnectButton_11Pushed(app,1);%las 6
                ConnectButton_12Pushed(app,1);%las 7
            end
            ConnectPM_1ButtonPushed(app,1)
            LRConnectButton_2Pushed(app,1)
        end

        % Value changed function: Switch_16
        function Switch_16ValueChanged(app, event)
            value = app.Switch_16.Value;
            value = contains(value,'On');
            if ~app.ld("Laser403").is_fault(1)
                app.ld("Laser403").set_state(1,value);
                app.LasingLamp_6.Color = app.lightIO(value);
            end
        end

        % Value changed function: Switch_18
        function Switch_18ValueChanged(app, event)
            value = app.Switch_18.Value;
            value = contains(value,'On');
            if ~app.ld("Laser640").is_fault(1)
                app.ld("Laser640").set_state(1,value);
                app.LasingLamp_7.Color = app.lightIO(value);
            end
        end

        % Value changed function: PowermWEditField_6
        function PowermWEditField_6ValueChanged(app, event)
            value = app.PowermWEditField_6.Value;
            if app.ld("Laser403").is_fault(1)
                return;
            end
            if app.ld("Laser403").is_conwav(1)
                app.ld("Laser403").set_power(1,.001*value);
            end
        end

        % Value changed function: PowermWEditField_7
        function PowermWEditField_7ValueChanged(app, event)
            value = app.PowermWEditField_7.Value;
            if app.ld("Laser640").is_fault(1)
                return;
            end
            if app.ld("Laser640").is_conwav(1)
                app.ld("Laser640").set_power(1,.001*value);
            end
        end

        % Clicked callback: DropDown_10
        function DropDown_10Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Laser403") = sprintf("%s",app.DropDown_10.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Laser403")),'visible',1);
            end
        end

        % Clicked callback: DropDown_11
        function DropDown_11Clicked(app, event)
            value = event.InteractionInformation.Item;
            if ~isempty(value)
                app.com_dict("Laser640") = sprintf("%s",app.DropDown_11.Items{value});
                set(findobj(app.DevelopingTab,'Tag',app.comtag_dict("Laser640")),'visible',1);
            end
        end

        % Button pushed function: ConnectButton_11
        function ConnectButton_11Pushed(app, event)
            if ~app.init_dict("Laser403")
                app.ld("Laser403") = laser_driver(app.com_dict("Laser403"));
                if ~app.ld("Laser403").is_fault(1)
                    app.init_dict("Laser403") = true;
                    set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser403")),'visible',1);
                    app.LasingLamp_6.Color = app.cOff;
                    app.ConnectedLamp_12.Color = app.cOn;
                    app.Switch_16.Value = 'Off';
                    wavnm = app.ld("Laser403").get_wav(1);
                    app.wavLabel_6.Text = sprintf("%d",wavnm);

                    if ~app.ld("Laser403").is_conwav(1)
                        app.PowermWEditField_6Label.Visible = false;
                        app.PowermWEditField_6.Visible = false;
                    end
                    app.DropDown_10.Enable = false;
                else
                    app.ld("Laser403").terminate();
                end
            end
        end

        % Button pushed function: ConnectButton_12
        function ConnectButton_12Pushed(app, event)
            if ~app.init_dict("Laser640")
                app.ld("Laser640") = laser_driver(app.com_dict("Laser640"));
                if ~app.ld("Laser640").is_fault(1)
                    app.init_dict("Laser640") = true;
                    set(findobj(app.DevelopingTab,'Tag',app.tag_dict("Laser640")),'visible',1);
                    app.LasingLamp_7.Color = app.cOff;
                    app.ConnectedLamp_13.Color = app.cOn;
                    app.Switch_18.Value = 'Off';
                    wavnm = app.ld("Laser640").get_wav(1);
                    app.wavLabel_6.Text = sprintf("%d",wavnm);

                    if ~app.ld("Laser640").is_conwav(1)
                        app.PowermWEditField_7Label.Visible = false;
                        app.PowermWEditField_7.Visible = false;
                    end
                    app.DropDown_11.Enable = false;
                else
                    app.ld("Laser640").terminate();
                end
            end
        end

        % Value changed function: Switch_15
        function Switch_15ValueChanged(app, event)
            value = app.Switch_15.Value;
            if contains(value,'Open')
                app.lash.open(6);
                app.OpenedLamp_8.Color = app.cOn;
            else
                app.lash.close(6);
                app.OpenedLamp_8.Color = app.cOff;
            end
        end

        % Value changed function: Switch_17
        function Switch_17ValueChanged(app, event)
            value = app.Switch_17.Value;
            if contains(value,'Open')
                app.lash.open(7);
                app.OpenedLamp_9.Color = app.cOn;
            else
                app.lash.close(7);
                app.OpenedLamp_9.Color = app.cOff;
            end
        end

        % Button pushed function: ConnectButton_10
        function ConnectButton_10Pushed(app, event)
            if ~app.init_dict("CryoCooler")
                app.cc = CryoCooler(app.com_dict("CryoCooler"));
                app.init_dict("CryoCooler") = true;
                set(findobj(app.DevelopingTab,'Tag',app.tag_dict("CryoCooler")),'visible',1);
                app.ConnectedLamp_11.Color = app.cOn;
                app.Switch_14.Value = 'Off';
                cT = app.cc.readColdHeadTemperature();
                app.tempKLabel.Text = sprintf("%.2f\n%.2f",app.cc.readColdHeadTemperature(),app.cc.readOutputPower);
                app.DropDown_12.Enable = false;
            end
        end

        % Button pushed function: ReadTempPowerKWButton
        function ReadTempPowerKWButtonPushed(app, event)
            app.tempKLabel.Text = sprintf("%.2f\n%.2f",app.cc.readColdHeadTemperature(),app.cc.readOutputPower);
        end

        % Value changed function: SetTemperatureEditField
        function SetTemperatureEditFieldValueChanged(app, event)
            value = app.SetTemperatureEditField.Value;
            app.cc.setTemperature(value);
        end

        % Button pushed function: ReadTSetKButton
        function ReadTSetKButtonPushed(app, event)
            cVs = app.cc.readSetpointTemp();
            app.setKcurrentKLabel.Text = sprintf("Set: %.1f",cVs);
        end

        % Value changed function: Switch_14
        function Switch_14ValueChanged(app, event)
            value = app.Switch_14.Value;
             if contains(value,'On')
                app.cc.turnOn;
            else
                app.cc.turnOff;
            end
        end

        % Value changed function: EffusionCellCheckBox
        function EffusionCellCheckBoxValueChanged(app, event)
            value = app.EffusionCellCheckBox.Value;
            
        end

        % Button pushed function: LoadDesigningButton
        function LoadDesigningButtonPushed(app, event)
            % each row is a list of target RT values and their weights WRT
            % the loss function
            % is first
            app.selected_file_design = uigetfile('*.xlsx', 'Select an Excel file');
            % if class(app.selected_file_design) == 'double'
            %     return
            % end
            app.design_data = readtable(app.selected_file_design);
            app.design_set = true;
            disp(app.design_data)
            app.design_target_num = app.design_data{1,2};
            app.design_sample_num = size(app.design_data,1);
        end

        % Button pushed function: RunDesigningButton
        function RunDesigningButtonPushed(app, event)
            % if ~app.training_set || ~app.design_set % for passive samples
            %     return
            % end

            % procedures if active training break in the middle: 1. change
            % for i = 5:ArgMaxVarRetrains, get rid of the wasted substrate
            % delete the waste substrate data in excel
            % 2. comment the line that converts csv to excel
            % do first passive training stuff
            data_programmed_filename_noext = "PassiveTraing20240517"; %"Filtered_Data_2.07"; % TODO
            % data_programmed_filename_noext = sprintf("ActiveTraining_%s",datestr(now,'mm_dd'));
            data_programmed_filename = data_programmed_filename_noext + ".csv";
            % app.RunTable(data_programmed_filename,true); % TODO runs app.selected_file which is PRE ACTIVE LEARNING

            transfer_file = sprintf("traininglog_%s.csv",datestr(now,'mm_dd'));
            %system("python3 ArgmaxVarRetrainRequest.py "+data_programmed_filename); %converts to xlsx TODO

            data_programmed_filename = data_programmed_filename_noext + ".xlsx";
            ArgMaxVarRetrains = 8; % TODO
            for i = 2:ArgMaxVarRetrains % TODO
                % 1. Grow calibration layer
                next_substrate = app.design_data{i,1};
                calibrationcsv = sprintf("CalibrationLayer_%s_%d.csv",datestr(now,'mm_dd'),i);
                app.RunCalib(next_substrate, calibrationcsv);
                % 2. Ask for recommendation
                if i == 1
                    [status, cmdout] = system("python3 ArgmaxVarRetrainRequest.py " + data_programmed_filename + " Output_" + calibrationcsv + " " + transfer_file);
                else
                    [status, cmdout] = system("python3 ArgmaxVarRetrainRequest.py " + data_programmed_filename + " Output_" + calibrationcsv + " " + transfer_file + " True");  % Or False
                end

                disp(cmdout);

                % Optionally, handle the error based on the status
                if status ~= 0
                    disp('Python script encountered an error.');
                    fatal_message = "Python";
                    fatal_body = fatal_message;
                    app.fatal_terminate(fatal_body,fatal_message,true);
                end
    
                % 3. Do Recommendation
                fileID = fopen(transfer_file, 'r');
                transfer_data = textscan(fileID, '%s', 'Delimiter', '\n');
                fclose(fileID);
                last_line = transfer_data{1}{end};
                target_values = strsplit(last_line, ',');
                temp = str2num(target_values{3});
                %time = 6000+100*(910-temp); 
                % time = exp((-temp-298)*.020118)*1.75884*(1E14); % new 5/7
                time = 3.2163E14*exp(-2.8490E-2*temp); % new 5/17
                disp(["Growing: Time (s): ",time," Temperature (C): ",temp]);
                
                % make a spreadsheet with the single grwoth
                new_input_filename = sprintf("ActiveTrainInput_%d_%d.csv", i,round(temp));
                app.build_table(next_substrate,temp,time,new_input_filename);
                % run the new input
                app.selected_file = new_input_filename;
                app.load_in_file() % uses app.selected_file to update setup
                new_output_filename = sprintf("ActiveTrainOutput_%d_%d.csv", i,round(temp));
                app.RunTable(new_output_filename,false);
                % send the data back to python to update model which should
                % affect whatever the undelrying model in RequestGrowth.py
                % is calling
                totalcsv = sprintf("FullCycle_%d.csv",i);
                system("python3 .\ReshapeCalibGrowth.py Output_"+calibrationcsv+" "+new_output_filename+" "+totalcsv)
                system("python3 .\AppendToCurrent.py "+data_programmed_filename+" "+totalcsv)
            end
            % now do active testing ASSUMES: excel called
            % data_programed_filename with all current data, model_R.pth,
            % model_A.pth, scaler.pkl
    
            return; % ACTIVE TRAINING ONLY, NO ACTIVE TESTING

            target_num = 1;
            this_target_runs = 0;
            max_runs_per_target = 3; % TODO CHANGEBACK
            for i = 1:app.design_sample_num
                % 1. Grow calibration layer
                next_substrate = app.design_data{i,1};
                calibrationcsv = sprintf("CalibrationLayer_%s_%d.csv",datestr(now,'mm_dd'),i);
                app.RunCalib(next_substrate, calibrationcsv);
                % 2. Ask for recommendation
                sample_target_i = app.getSampleTarget(i,target_num);
                [status, cmdout] = system("python3 ExpectedImprovementRequest.py " +  data_programmed_filename + " Output_" + calibrationcsv + " " + sample_target_i + " " + transfer_file);

                disp(cmdout);

                % Optionally, handle the error based on the status
                if status ~= 0
                    disp('Python script encountered an error.');
                    fatal_message = "Python";
                    fatal_body = cmdout;
                    app.fatal_terminate(fatal_body,fatal_message,true);
                end
    
                % 3. Do Recommendation
                fileID = fopen(transfer_file, 'r');
                transfer_data = textscan(fileID, '%s', 'Delimiter', '\n');
                fclose(fileID);
                last_line = transfer_data{1}{end};
                target_values = strsplit(last_line, ',');
                temp = str2num(target_values{4}); % note that this is not 3 like above
                time = str2num(target_values{5});
                disp(["Growing: Time (s): ",time," Temperature (C): ",temp]);
                % temp = 800+i/10; % TODO delete
                % time = 10; % TODO delete
                % make a spreadsheet with the single grwoth
                new_input_filename = sprintf("ActiveTrainInput_%d_%d.csv", i,round(temp));
                app.build_table(next_substrate,temp,time,new_input_filename);
                % run the new input
                app.selected_file = new_input_filename;
                app.load_in_file() % uses app.selected_file to update setup
                new_output_filename = sprintf("ActiveTrainOutput_%d_%d.csv", i,round(temp));
                app.RunTable(new_output_filename,false);
                totalname = sprintf("FullCycle_%d",i);
                totalcsv = totalname + ".csv";
                totalxlsx = totalname + ".xlsx";
                system("python3 .\ReshapeCalibGrowth.py Output_"+calibrationcsv+" "+new_output_filename+" "+totalcsv)
                system("python3 ArgmaxVarRetrainRequest.py " + totalcsv); % convert to excel
                % send the data back to python to update model
                system("python3 .\AppendToCurrent.py "+data_programmed_filename+" "+totalcsv)
                % figure out whether it converged or not and make decision
                totaldata = readtable(totalxlsx);
                Rlist = totaldata.R(end+1-app.nlasers:end);
                Alist = totaldata.A(end+1-app.nlasers:end);
                disp("R");
                disp(Rlist);
                disp("A");
                disp(Alist);
                thisLoss = app.Loss(sample_target_i,Rlist,Alist);
                Predicted_Loss = str2num(target_values{6});
                fprintf("Predicted Loss %f thisLoss %f",Predicted_Loss,thisLoss)
                this_target_runs = this_target_runs + 1;
                dt = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                setnext = false;
                if thisLoss < 0.025
                    newline = {dt,"Success",sample_target_i,thisLoss,temp,time};
                    writecell(newline, transfer_file, 'WriteMode', 'append');
                    fprintf("Saved. Growth Successful after %d attempt(s). Onto the next target.",this_target_runs);
                    setnext = true;
                else
                    newline = {dt,"Failure",sample_target_i,thisLoss,temp,time};
                    writecell(newline, transfer_file, 'WriteMode', 'append');
                    if this_target_runs == max_runs_per_target
                        fprintf("Saved. Final growth unsuccessful after %d attempts. Onto the next target.",max_runs_per_target);
                        setnext = true;
                    else
                        fprintf("Saved. Growth Unsuccessful (%d / %d). Retrying.",this_target_runs,max_runs_per_target)
                    end
                end
                if setnext
                    this_target_runs = 0;
                    if target_num + 1 > app.design_target_num
                        disp("Out of targets. Exiting...")
                        break;
                    else
                        target_num = target_num + 1;
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout13 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout13);
            app.ConnectionsTab = uitab(app.TabGroup);
            app.ConnectionsTab.Title = 'Connections';
            app.GridLayout2 = uigridlayout(app.ConnectionsTab);
            app.LaserLabel_1 = uilabel(app.GridLayout2);
            app.LaserLabel_1.Text = 'Laser 1 443 nm (COM5)';
            app.LaserLabel_2 = uilabel(app.GridLayout2);
            app.LaserLabel_2.Text = 'Laser 2 514 nm (COM4)';
            app.LaserLabel_3 = uilabel(app.GridLayout2);
            app.LaserLabel_3.Text = 'Laser 3 689 nm (COM11)';
            app.LaserLabel_4 = uilabel(app.GridLayout2);
            app.LaserLabel_4.Text = 'Laser 4 781 nm (COM6)';
            app.LaserLabel_5 = uilabel(app.GridLayout2);
            app.LaserLabel_5.Text = 'Laser 5 817 nm (COM7)';
            app.DropDown_1 = uidropdown(app.GridLayout2);
            app.DropDown_1.Items = {'COM5'};
            app.DropDown_1.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_1Opening, true);
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_1.ClickedFcn = createCallbackFcn(app, @DropDown_1Clicked, true);
            app.DropDown_1.Value = 'COM5';
            app.DropDown_2 = uidropdown(app.GridLayout2);
            app.DropDown_2.Items = {'COM4'};
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2Opening, true);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.ClickedFcn = createCallbackFcn(app, @DropDown_2Clicked, true);
            app.DropDown_2.Value = 'COM4';
            app.DropDown_3 = uidropdown(app.GridLayout2);
            app.DropDown_3.Items = {'COM11'};
            app.DropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_3Opening, true);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.ClickedFcn = createCallbackFcn(app, @DropDown_3Clicked, true);
            app.DropDown_3.Value = 'COM11';
            app.DropDown_4 = uidropdown(app.GridLayout2);
            app.DropDown_4.Items = {'COM6'};
            app.DropDown_4.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_4Opening, true);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.ClickedFcn = createCallbackFcn(app, @DropDown_4Clicked, true);
            app.DropDown_4.Value = 'COM6';
            app.DropDown_5 = uidropdown(app.GridLayout2);
            app.DropDown_5.Items = {'COM7'};
            app.DropDown_5.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_5Opening, true);
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.ClickedFcn = createCallbackFcn(app, @DropDown_5Clicked, true);
            app.DropDown_5.Value = 'COM7';
            app.LaserLabel_6 = uilabel(app.GridLayout2);
            app.LaserLabel_6.Text = 'Rail (COM10)';
            app.DropDown_6 = uidropdown(app.GridLayout2);
            app.DropDown_6.Items = {'COM10'};
            app.DropDown_6.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_6Opening, true);
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.ClickedFcn = createCallbackFcn(app, @DropDown_6Clicked, true);
            app.DropDown_6.Value = 'COM10';
            app.LaserLabel_7 = uilabel(app.GridLayout2);
            app.LaserLabel_7.Text = 'Shutter (COM17)';
            app.DropDown_7 = uidropdown(app.GridLayout2);
            app.DropDown_7.Items = {'COM17'};
            app.DropDown_7.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_7Opening, true);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.ClickedFcn = createCallbackFcn(app, @DropDown_7Clicked, true);
            app.DropDown_7.Value = 'COM17';
            app.TemperatureController1012040165EditFieldLabel = uilabel(app.GridLayout2);
            app.TemperatureController1012040165EditFieldLabel.Text = 'Temperature Controller (10.120.40.165)';
            app.TemperatureController1012040165EditField = uieditfield(app.GridLayout2, 'text');
            app.TemperatureController1012040165EditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureController1012040165EditFieldValueChanged, true);
            app.TemperatureController1012040165EditField.ValueChangingFcn = createCallbackFcn(app, @TemperatureController1012040165EditFieldValueChanging, true);
            app.LaserLabel_8 = uilabel(app.GridLayout2);
            app.LaserLabel_8.Text = 'Manipulator (COM9)';
            app.DropDown_8 = uidropdown(app.GridLayout2);
            app.DropDown_8.Items = {'COM9'};
            app.DropDown_8.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_8Opening, true);
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_8ValueChanged, true);
            app.DropDown_8.ClickedFcn = createCallbackFcn(app, @DropDown_8Clicked, true);
            app.DropDown_8.Value = 'COM9';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = 'If the controller has just been plugged into an active Ethernet port. This will reset. Note that in order to connect, this computer must be on uchicago-secure. To find the IP address of the Eurotherm, use the PAGE button to navigate to the Comms H menu, then cycle through the menu options by repeatedly pressing ';
            app.LaserLabel_9 = uilabel(app.GridLayout2);
            app.LaserLabel_9.Text = 'Laser Shutters (COM12)';
            app.DropDown_9 = uidropdown(app.GridLayout2);
            app.DropDown_9.Items = {'COM12'};
            app.DropDown_9.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_9Opening2, true);
            app.DropDown_9.ValueChangedFcn = createCallbackFcn(app, @DropDown_9ValueChanged, true);
            app.DropDown_9.ClickedFcn = createCallbackFcn(app, @DropDown_9Clicked, true);
            app.DropDown_9.Value = 'COM12';
            app.LaserLabel_10 = uilabel(app.GridLayout2);
            app.LaserLabel_10.Text = 'Laser 6 403 nm (COM3)';
            app.LaserLabel_11 = uilabel(app.GridLayout2);
            app.LaserLabel_11.Text = 'Laser 7 640 nm (COM13)';
            app.DropDown_10 = uidropdown(app.GridLayout2);
            app.DropDown_10.Items = {'COM3'};
            app.DropDown_10.ClickedFcn = createCallbackFcn(app, @DropDown_10Clicked, true);
            app.DropDown_10.Value = 'COM3';
            app.DropDown_11 = uidropdown(app.GridLayout2);
            app.DropDown_11.Items = {'COM13'};
            app.DropDown_11.ClickedFcn = createCallbackFcn(app, @DropDown_11Clicked, true);
            app.DropDown_11.Value = 'COM13';
            app.LaserLabel_12 = uilabel(app.GridLayout2);
            app.LaserLabel_12.Text = 'Cryo Cooler (COM14)';
            app.DropDown_12 = uidropdown(app.GridLayout2);
            app.DropDown_12.Items = {'COM14'};
            app.DropDown_12.Value = 'COM14';
            app.ExperimentingTab = uitab(app.TabGroup);
            app.ExperimentingTab.Title = 'Experimenting';
            app.ExperimentingTab.ButtonDownFcn = createCallbackFcn(app, @ExperimentingTabButtonDown, true);
            app.GridLayout9 = uigridlayout(app.ExperimentingTab);
            app.UITable = uitable(app.GridLayout9);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.TestConfigurationPanel = uipanel(app.GridLayout9);
            app.TestConfigurationPanel.Title = 'Test Configuration';
            app.GridLayout18 = uigridlayout(app.TestConfigurationPanel);
            app.LoadSpreadsheetButton = uibutton(app.GridLayout18, 'push');
            app.LoadSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpreadsheetButtonPushed, true);
            app.LoadSpreadsheetButton.Text = 'Load Spreadsheet';
            app.RunSpreadsheetButton = uibutton(app.GridLayout18, 'push');
            app.RunSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpreadsheetButtonPushed, true);
            app.RunSpreadsheetButton.Visible = 'off';
            app.RunSpreadsheetButton.Text = 'Run Spreadsheet';
            app.RunSingleButton = uibutton(app.GridLayout18, 'push');
            app.RunSingleButton.ButtonPushedFcn = createCallbackFcn(app, @RunSingleButtonPushed, true);
            app.RunSingleButton.Text = 'Run Single';
            app.ModeButtonGroup = uibuttongroup(app.GridLayout18);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.SingleButton = uitogglebutton(app.ModeButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.ProgrammedButton = uitogglebutton(app.ModeButtonGroup);
            app.ProgrammedButton.Text = 'Programmed';
            app.DesigningButton = uitogglebutton(app.ModeButtonGroup);
            app.DesigningButton.Text = 'Designing';
            app.EffusionCellCheckBox = uicheckbox(app.GridLayout18);
            app.EffusionCellCheckBox.ValueChangedFcn = createCallbackFcn(app, @EffusionCellCheckBoxValueChanged, true);
            app.EffusionCellCheckBox.Text = 'Effusion Cell';
            app.ManipulatorMovementCheckBox = uicheckbox(app.GridLayout18);
            app.ManipulatorMovementCheckBox.Text = 'Manipulator Movement';
            app.DataCollectionCheckBox = uicheckbox(app.GridLayout18);
            app.DataCollectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.DataCollectionCheckBox.Enable = 'off';
            app.DataCollectionCheckBox.Text = 'Data Collection';
            app.DataCollectionCheckBox.Value = true;
            app.LoadDesigningButton = uibutton(app.GridLayout18, 'push');
            app.LoadDesigningButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDesigningButtonPushed, true);
            app.LoadDesigningButton.Text = 'Load Designing';
            app.RunDesigningButton = uibutton(app.GridLayout18, 'push');
            app.RunDesigningButton.ButtonPushedFcn = createCallbackFcn(app, @RunDesigningButtonPushed, true);
            app.RunDesigningButton.Text = 'Run Designing';
            app.TempCEditFieldLabel = uilabel(app.GridLayout18);
            app.TempCEditFieldLabel.Text = 'Temp. (C)';
            app.TempCEditField = uieditfield(app.GridLayout18, 'numeric');
            app.DurationsEditFieldLabel = uilabel(app.GridLayout18);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.GridLayout18, 'numeric');
            app.ActiveRunPanel = uipanel(app.GridLayout9);
            app.ActiveRunPanel.Title = 'Active Run';
            app.GridLayout10 = uigridlayout(app.ActiveRunPanel);
            app.STOPALLButton = uibutton(app.GridLayout10, 'push');
            app.STOPALLButton.ButtonPushedFcn = createCallbackFcn(app, @STOPALLButtonPushed, true);
            app.STOPALLButton.Text = 'STOP ALL';
            app.FREEZEButton = uibutton(app.GridLayout10, 'push');
            app.FREEZEButton.ButtonPushedFcn = createCallbackFcn(app, @FREEZEButtonPushed, true);
            app.FREEZEButton.Text = 'FREEZE';
            app.TestDurationLabel = uilabel(app.GridLayout10);
            app.TestDurationLabel.Text = 'Test Duration:';
            app.TestTemperatureLabel = uilabel(app.GridLayout10);
            app.TestTemperatureLabel.Text = 'Test Temperature:';
            app.CurrentTemperatureLabel = uilabel(app.GridLayout10);
            app.CurrentTemperatureLabel.Text = 'Current Temperature:';
            app.CurrentDurationLabel = uilabel(app.GridLayout10);
            app.CurrentDurationLabel.Text = 'Current Duration:';
            app.tdurLabel = uilabel(app.GridLayout10);
            app.tdurLabel.Text = 'tdur';
            app.ttemLabel = uilabel(app.GridLayout10);
            app.ttemLabel.Text = 'ttem';
            app.cdurLabel = uilabel(app.GridLayout10);
            app.cdurLabel.Text = 'cdur';
            app.ctemLabel = uilabel(app.GridLayout10);
            app.ctemLabel.Text = 'ctem';
            app.CurrentSetpointLabel = uilabel(app.GridLayout10);
            app.CurrentSetpointLabel.Text = 'Current Setpoint:';
            app.csetLabel = uilabel(app.GridLayout10);
            app.csetLabel.Text = 'cset';
            app.CurrentSubstrateLabel = uilabel(app.GridLayout10);
            app.CurrentSubstrateLabel.Text = 'Current Substrate:';
            app.csubLabel = uilabel(app.GridLayout10);
            app.csubLabel.Text = 'csub';
            app.GrowingLabel = uilabel(app.GridLayout10);
            app.GrowingLabel.Text = 'Growing:';
            app.GrowingLamp = uilamp(app.GridLayout10);
            app.LasersActiveLabel = uilabel(app.GridLayout10);
            app.LasersActiveLabel.Text = 'Lasers Active:';
            app.LasersActiveLamp = uilamp(app.GridLayout10);
            app.CurrentRateCminLabel = uilabel(app.GridLayout10);
            app.CurrentRateCminLabel.Text = 'Current Rate (C/min):';
            app.crateLabel = uilabel(app.GridLayout10);
            app.crateLabel.Text = 'crate';
            app.CurrentTestLabel = uilabel(app.GridLayout10);
            app.CurrentTestLabel.Text = 'Current Test #:';
            app.ctestLabel = uilabel(app.GridLayout10);
            app.ctestLabel.Text = 'ctest';
            app.TabGroup2 = uitabgroup(app.GridLayout9);
            app.Comms = uitab(app.TabGroup2);
            app.Comms.Title = 'Communications';
            app.GridLayout11 = uigridlayout(app.Comms);
            app.CommunicationsPanel = uipanel(app.GridLayout11);
            app.CommunicationsPanel.Title = 'Communications';
            app.GridLayout8 = uigridlayout(app.CommunicationsPanel);
            app.Laser2LampLabel = uilabel(app.GridLayout8);
            app.Laser2LampLabel.Text = 'Laser 2';
            app.Laser2Lamp = uilamp(app.GridLayout8);
            app.Laser1LampLabel = uilabel(app.GridLayout8);
            app.Laser1LampLabel.Text = 'Laser 1';
            app.Laser1Lamp = uilamp(app.GridLayout8);
            app.Laser3LampLabel = uilabel(app.GridLayout8);
            app.Laser3LampLabel.Text = 'Laser 3';
            app.Laser3Lamp = uilamp(app.GridLayout8);
            app.Laser4LampLabel = uilabel(app.GridLayout8);
            app.Laser4LampLabel.Text = 'Laser 4';
            app.Laser4Lamp = uilamp(app.GridLayout8);
            app.Laser5LampLabel = uilabel(app.GridLayout8);
            app.Laser5LampLabel.Text = 'Laser 5';
            app.Laser5Lamp = uilamp(app.GridLayout8);
            app.SensorRLampLabel = uilabel(app.GridLayout8);
            app.SensorRLampLabel.Text = 'Sensor (R)';
            app.SensorRLamp = uilamp(app.GridLayout8);
            app.SensorTLampLabel = uilabel(app.GridLayout8);
            app.SensorTLampLabel.Text = 'Sensor (T)';
            app.SensorTLamp = uilamp(app.GridLayout8);
            app.LaserRailLampLabel = uilabel(app.GridLayout8);
            app.LaserRailLampLabel.Text = 'Laser Rail';
            app.LaserRailLamp = uilamp(app.GridLayout8);
            app.ShutterLampLabel = uilabel(app.GridLayout8);
            app.ShutterLampLabel.Text = 'Shutter';
            app.ShutterLamp = uilamp(app.GridLayout8);
            app.TemperatureLampLabel = uilabel(app.GridLayout8);
            app.TemperatureLampLabel.Text = 'Temperature';
            app.TemperatureLamp = uilamp(app.GridLayout8);
            app.SensorPLampLabel = uilabel(app.GridLayout8);
            app.SensorPLampLabel.Text = 'Sensor (P)';
            app.SensorPLamp = uilamp(app.GridLayout8);
            app.ManipulatorLampLabel = uilabel(app.GridLayout8);
            app.ManipulatorLampLabel.Text = 'Manipulator';
            app.ManipulatorLamp = uilamp(app.GridLayout8);
            app.TestCommunicationsButton = uibutton(app.GridLayout8, 'push');
            app.TestCommunicationsButton.ButtonPushedFcn = createCallbackFcn(app, @TestCommunicationsButtonPushed, true);
            app.TestCommunicationsButton.Text = 'Test Communications';
            app.LaserShuttersLampLabel = uilabel(app.GridLayout8);
            app.LaserShuttersLampLabel.Text = 'Laser Shutters';
            app.LaserShuttersLamp = uilamp(app.GridLayout8);
            app.Laser6LampLabel = uilabel(app.GridLayout8);
            app.Laser6LampLabel.Text = 'Laser 6';
            app.Laser6Lamp = uilamp(app.GridLayout8);
            app.Laser7LampLabel = uilabel(app.GridLayout8);
            app.Laser7LampLabel.Text = 'Laser 7';
            app.Laser7Lamp = uilamp(app.GridLayout8);
            app.ActiveTestTab = uitab(app.TabGroup2);
            app.ActiveTestTab.Title = 'Active Test';
            app.GridLayout12 = uigridlayout(app.ActiveTestTab);
            app.UIAxes = uiaxes(app.GridLayout12);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LatestDataLabel = uilabel(app.GridLayout12);
            app.LatestDataLabel.Text = 'Latest Data:';
            app.las1Label = uilabel(app.GridLayout12);
            app.las1Label.Text = 'las1';
            app.las2Label = uilabel(app.GridLayout12);
            app.las2Label.Text = 'las2';
            app.las3Label = uilabel(app.GridLayout12);
            app.las3Label.Text = 'las3';
            app.las4Label = uilabel(app.GridLayout12);
            app.las4Label.Text = 'las4';
            app.las5Label = uilabel(app.GridLayout12);
            app.las5Label.Text = 'las5';
            app.ManipulatorMovingLampLabel = uilabel(app.GridLayout12);
            app.ManipulatorMovingLampLabel.Text = 'Manipulator Moving';
            app.ManipulatorMovingLamp = uilamp(app.GridLayout12);
            app.TemperatureStablizing1LampLabel = uilabel(app.GridLayout12);
            app.TemperatureStablizing1LampLabel.Text = 'Temperature Stablizing 1';
            app.TemperatureStablizing1Lamp = uilamp(app.GridLayout12);
            app.Measuring1LampLabel = uilabel(app.GridLayout12);
            app.Measuring1LampLabel.Text = 'Measuring 1';
            app.Measuring1Lamp = uilamp(app.GridLayout12);
            app.Growing1LampLabel = uilabel(app.GridLayout12);
            app.Growing1LampLabel.Text = 'Growing 1';
            app.Growing1Lamp = uilamp(app.GridLayout12);
            app.las6Label = uilabel(app.GridLayout12);
            app.las6Label.Text = 'las6';
            app.las7Label = uilabel(app.GridLayout12);
            app.las7Label.Text = 'las7';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.TempCEditFieldLabel_2 = uilabel(app.GridLayout9);
            app.TempCEditFieldLabel_2.Text = 'Close Spreadsheet Before Run!!!';
            app.DevelopingTab = uitab(app.TabGroup);
            app.DevelopingTab.Title = 'Developing';
            app.DevelopingTab.ButtonDownFcn = createCallbackFcn(app, @DevelopingTabButtonDown, true);
            app.GridLayout17 = uigridlayout(app.DevelopingTab);
            app.PowerMetersPanel = uipanel(app.GridLayout17);
            app.PowerMetersPanel.Title = 'Power Meters';
            app.GridLayout3 = uigridlayout(app.PowerMetersPanel);
            app.PMLabel_1 = uilabel(app.GridLayout3);
            app.PMLabel_1.Text = 'Transmission';
            app.PMLabel_2 = uilabel(app.GridLayout3);
            app.PMLabel_2.Text = 'Reflection';
            app.PMLabel_3 = uilabel(app.GridLayout3);
            app.PMLabel_3.Text = 'Power (total)';
            app.ConnectPM_1 = uibutton(app.GridLayout3, 'push');
            app.ConnectPM_1.ButtonPushedFcn = createCallbackFcn(app, @ConnectPM_1ButtonPushed, true);
            app.ConnectPM_1.Text = 'Connect';
            app.ConnectPM_2 = uibutton(app.GridLayout3, 'push');
            app.ConnectPM_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectPM_2ButtonPushed, true);
            app.ConnectPM_2.Text = 'Connect';
            app.ConnectPM_3 = uibutton(app.GridLayout3, 'push');
            app.ConnectPM_3.ButtonPushedFcn = createCallbackFcn(app, @ConnectPM_3ButtonPushed, true);
            app.ConnectPM_3.Text = 'Connect';
            app.SetFilterLabel_2 = uilabel(app.GridLayout3);
            app.SetFilterLabel_2.Tag = 'pm2';
            app.SetFilterLabel_2.Text = 'Set Filter';
            app.SetFilterLabel_3 = uilabel(app.GridLayout3);
            app.SetFilterLabel_3.Tag = 'pm3';
            app.SetFilterLabel_3.Text = 'Set Filter';
            app.CollectDataLabel_2 = uilabel(app.GridLayout3);
            app.CollectDataLabel_2.Tag = 'pm2';
            app.CollectDataLabel_2.Text = 'Collect Data';
            app.CollectDataLabel_3 = uilabel(app.GridLayout3);
            app.CollectDataLabel_3.Tag = 'pm3';
            app.CollectDataLabel_3.Text = 'Collect Data';
            app.SetRangeDropDownLabel_1 = uilabel(app.GridLayout3);
            app.SetRangeDropDownLabel_1.Tag = 'pm1';
            app.SetRangeDropDownLabel_1.Text = 'Set Range';
            app.SetRangeDropDown_1 = uidropdown(app.GridLayout3);
            app.SetRangeDropDown_1.Items = {};
            app.SetRangeDropDown_1.DropDownOpeningFcn = createCallbackFcn(app, @SetRangeDropDown_1Opening, true);
            app.SetRangeDropDown_1.ValueChangedFcn = createCallbackFcn(app, @SetRangeDropDown_1ValueChanged, true);
            app.SetRangeDropDown_1.Tag = 'pm1';
            app.SetRangeDropDown_1.Value = {};
            app.ONButton_1 = uibutton(app.GridLayout3, 'state');
            app.ONButton_1.ValueChangedFcn = createCallbackFcn(app, @ONButton_1ValueChanged, true);
            app.ONButton_1.Tag = 'pm1';
            app.ONButton_1.Text = 'ON';
            app.SetFilterLabel_1 = uilabel(app.GridLayout3);
            app.SetFilterLabel_1.Tag = 'pm1';
            app.SetFilterLabel_1.Text = 'Set Filter';
            app.SetnmEditFieldLabel_1 = uilabel(app.GridLayout3);
            app.SetnmEditFieldLabel_1.Tag = 'pm1';
            app.SetnmEditFieldLabel_1.Text = 'Set λ (nm)';
            app.SetnmEditField_1 = uieditfield(app.GridLayout3, 'numeric');
            app.SetnmEditField_1.RoundFractionalValues = 'on';
            app.SetnmEditField_1.ValueChangedFcn = createCallbackFcn(app, @SetnmEditField_1ValueChanged, true);
            app.SetnmEditField_1.Tag = 'pm1';
            app.SetRangeDropDownLabel_2 = uilabel(app.GridLayout3);
            app.SetRangeDropDownLabel_2.Tag = 'pm2';
            app.SetRangeDropDownLabel_2.Text = 'Set Range';
            app.SetRangeDropDown_2 = uidropdown(app.GridLayout3);
            app.SetRangeDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @SetRangeDropDown_2Opening, true);
            app.SetRangeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SetRangeDropDown_2ValueChanged, true);
            app.SetRangeDropDown_2.Tag = 'pm2';
            app.SetRangeDropDownLabel_3 = uilabel(app.GridLayout3);
            app.SetRangeDropDownLabel_3.Tag = 'pm3';
            app.SetRangeDropDownLabel_3.Text = 'Set Range';
            app.SetRangeDropDown_3 = uidropdown(app.GridLayout3);
            app.SetRangeDropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @SetRangeDropDown_3Opening, true);
            app.SetRangeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SetRangeDropDown_3ValueChanged, true);
            app.SetRangeDropDown_3.Tag = 'pm3';
            app.ONButton_2 = uibutton(app.GridLayout3, 'state');
            app.ONButton_2.ValueChangedFcn = createCallbackFcn(app, @ONButton_2ValueChanged, true);
            app.ONButton_2.Tag = 'pm2';
            app.ONButton_2.Text = 'ON';
            app.ONButton_3 = uibutton(app.GridLayout3, 'state');
            app.ONButton_3.ValueChangedFcn = createCallbackFcn(app, @ONButton_3ValueChanged, true);
            app.ONButton_3.Tag = 'pm3';
            app.ONButton_3.Text = 'ON';
            app.SetnmEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.SetnmEditFieldLabel_2.Tag = 'pm2';
            app.SetnmEditFieldLabel_2.Text = 'Set λ (nm)';
            app.SetnmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.SetnmEditField_2.RoundFractionalValues = 'on';
            app.SetnmEditField_2.ValueChangedFcn = createCallbackFcn(app, @SetnmEditField_2ValueChanged, true);
            app.SetnmEditField_2.Tag = 'pm2';
            app.SetnmEditFieldLabel_3 = uilabel(app.GridLayout3);
            app.SetnmEditFieldLabel_3.Tag = 'pm3';
            app.SetnmEditFieldLabel_3.Text = 'Set λ (nm)';
            app.SetnmEditField_3 = uieditfield(app.GridLayout3, 'numeric');
            app.SetnmEditField_3.RoundFractionalValues = 'on';
            app.SetnmEditField_3.ValueChangedFcn = createCallbackFcn(app, @SetnmEditField_3ValueChanged, true);
            app.SetnmEditField_3.Tag = 'pm3';
            app.GOButton_2 = uibutton(app.GridLayout3, 'push');
            app.GOButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOButton_2Pushed, true);
            app.GOButton_2.Tag = 'pm2';
            app.GOButton_2.Text = 'GO';
            app.GOButton_3 = uibutton(app.GridLayout3, 'push');
            app.GOButton_3.ButtonPushedFcn = createCallbackFcn(app, @GOButton_3Pushed, true);
            app.GOButton_3.Tag = 'pm3';
            app.GOButton_3.Text = 'GO';
            app.GOButton_1 = uibutton(app.GridLayout3, 'push');
            app.GOButton_1.ButtonPushedFcn = createCallbackFcn(app, @GOButton_1Pushed, true);
            app.GOButton_1.Tag = 'pm1';
            app.GOButton_1.Text = 'GO';
            app.CollectDataLabel_1 = uilabel(app.GridLayout3);
            app.CollectDataLabel_1.Tag = 'pm1';
            app.CollectDataLabel_1.Text = 'Collect Data';
            app.PM_Lamp_1 = uilamp(app.GridLayout3);
            app.PM_Lamp_2 = uilamp(app.GridLayout3);
            app.PM_Lamp_3 = uilamp(app.GridLayout3);
            app.CP_1 = uilabel(app.GridLayout3);
            app.CP_1.Tag = 'pm1';
            app.CP_1.Text = 'power';
            app.CP_2 = uilabel(app.GridLayout3);
            app.CP_2.Tag = 'pm2';
            app.CP_2.Text = 'power';
            app.CP_3 = uilabel(app.GridLayout3);
            app.CP_3.Tag = 'pm3';
            app.CP_3.Text = 'power';
            app.CPL_1 = uilabel(app.GridLayout3);
            app.CPL_1.Tag = 'pm1';
            app.CPL_1.Text = 'Last (W)';
            app.CPL_2 = uilabel(app.GridLayout3);
            app.CPL_2.Tag = 'pm2';
            app.CPL_2.Text = 'Last (W)';
            app.CPL_3 = uilabel(app.GridLayout3);
            app.CPL_3.Tag = 'pm3';
            app.CPL_3.Text = 'Last (W)';
            app.LaserRailPanel = uipanel(app.GridLayout17);
            app.LaserRailPanel.Title = 'Laser Rail';
            app.GridLayout4 = uigridlayout(app.LaserRailPanel);
            app.LRPositionmmLabel = uilabel(app.GridLayout4);
            app.LRPositionmmLabel.Tag = 'lr1';
            app.LRPositionmmLabel.Text = 'Position (mm)';
            app.cposLabel = uilabel(app.GridLayout4);
            app.cposLabel.Tag = 'lr1';
            app.cposLabel.Text = 'cpos';
            app.LRUpdateButton = uibutton(app.GridLayout4, 'push');
            app.LRUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @LRUpdateButtonPushed, true);
            app.LRUpdateButton.Tag = 'lr1';
            app.LRUpdateButton.Text = 'Update';
            app.LRGoTommLabel = uilabel(app.GridLayout4);
            app.LRGoTommLabel.Tag = 'lr1';
            app.LRGoTommLabel.Text = 'Go To (mm)';
            app.LREditField = uieditfield(app.GridLayout4, 'numeric');
            app.LREditField.ValueChangedFcn = createCallbackFcn(app, @LREditFieldValueChanged, true);
            app.LREditField.Tag = 'lr1';
            app.LRConnectButton_2 = uibutton(app.GridLayout4, 'push');
            app.LRConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @LRConnectButton_2Pushed, true);
            app.LRConnectButton_2.Tag = 'lr11';
            app.LRConnectButton_2.Text = 'Connect';
            app.LRConnectedLamp_2Label = uilabel(app.GridLayout4);
            app.LRConnectedLamp_2Label.Tag = 'lr11';
            app.LRConnectedLamp_2Label.Text = 'Connected';
            app.LRConnectedLamp_2 = uilamp(app.GridLayout4);
            app.LRConnectedLamp_2.Tag = 'lr11';
            app.LRSwitch_2 = uiswitch(app.GridLayout4, 'slider');
            app.LRSwitch_2.Items = {'Moving', 'Stop'};
            app.LRSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LRSwitch_2ValueChanged, true);
            app.LRSwitch_2.Tag = 'lr1';
            app.LRSwitch_2.Value = 'Moving';
            app.SetpointLabel = uilabel(app.GridLayout4);
            app.SetpointLabel.Tag = 'lr1';
            app.SetpointLabel.Text = '0 42.5 85 127.5 170';
            app.ShutterPanel = uipanel(app.GridLayout17);
            app.ShutterPanel.Title = 'Shutter';
            app.GridLayout6 = uigridlayout(app.ShutterPanel);
            app.ConnectedLamp_7Label = uilabel(app.GridLayout6);
            app.ConnectedLamp_7Label.Tag = 'sh11';
            app.ConnectedLamp_7Label.Text = 'Connected';
            app.ConnectedLamp_7 = uilamp(app.GridLayout6);
            app.ConnectedLamp_7.Tag = 'sh11';
            app.ConnectButton_7 = uibutton(app.GridLayout6, 'push');
            app.ConnectButton_7.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_7Pushed, true);
            app.ConnectButton_7.Tag = 'sh11';
            app.ConnectButton_7.Text = 'Connect';
            app.Switch_6 = uiswitch(app.GridLayout6, 'slider');
            app.Switch_6.Items = {'Open', 'Close'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Tag = 'sh1';
            app.Switch_6.Value = 'Open';
            app.ConnectedLamp_10 = uilamp(app.GridLayout6);
            app.ConnectedLamp_10.Tag = 'sh11';
            app.TemperatureControllerPanel = uipanel(app.GridLayout17);
            app.TemperatureControllerPanel.Title = 'Temperature Controller';
            app.GridLayout5 = uigridlayout(app.TemperatureControllerPanel);
            app.ConnectButton_6 = uibutton(app.GridLayout5, 'push');
            app.ConnectButton_6.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_6Pushed, true);
            app.ConnectButton_6.Tag = 'tp11';
            app.ConnectButton_6.Text = 'Connect';
            app.ConnectedLamp_6Label = uilabel(app.GridLayout5);
            app.ConnectedLamp_6Label.Tag = 'tp11';
            app.ConnectedLamp_6Label.Text = 'Connected';
            app.ConnectedLamp_6 = uilamp(app.GridLayout5);
            app.ConnectedLamp_6.Tag = 'tp11';
            app.TempCLabel = uilabel(app.GridLayout5);
            app.TempCLabel.Tag = 'tp1';
            app.TempCLabel.Text = 'Temp. (C)';
            app.targetLabel = uilabel(app.GridLayout5);
            app.targetLabel.Tag = 'tp1';
            app.targetLabel.Text = 'target';
            app.StableAtTargetLampLabel = uilabel(app.GridLayout5);
            app.StableAtTargetLampLabel.Tag = 'tp1';
            app.StableAtTargetLampLabel.Text = 'Stable At Target?';
            app.StableAtTargetLamp = uilamp(app.GridLayout5);
            app.StableAtTargetLamp.Tag = 'tp1';
            app.tempLabel = uilabel(app.GridLayout5);
            app.tempLabel.Tag = 'tp1';
            app.tempLabel.Text = 'temp';
            app.UpdateButton = uibutton(app.GridLayout5, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tag = 'tp1';
            app.UpdateButton.Text = 'Update';
            app.GoToCEditFieldLabel = uilabel(app.GridLayout5);
            app.GoToCEditFieldLabel.Tag = 'tp1';
            app.GoToCEditFieldLabel.Text = 'Go To (C)';
            app.GoToCEditField = uieditfield(app.GridLayout5, 'numeric');
            app.GoToCEditField.ValueChangedFcn = createCallbackFcn(app, @GoToCEditFieldValueChanged, true);
            app.GoToCEditField.Tag = 'tp1';
            app.RampRateCminEditFieldLabel = uilabel(app.GridLayout5);
            app.RampRateCminEditFieldLabel.Tag = 'tp1';
            app.RampRateCminEditFieldLabel.Text = {'Ramp Rate'; '(C/min)'};
            app.RampRateCminEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RampRateCminEditField.ValueChangedFcn = createCallbackFcn(app, @RampRateCminEditFieldValueChanged, true);
            app.RampRateCminEditField.Tag = 'tp1';
            app.LaserPanel_1 = uipanel(app.GridLayout17);
            app.LaserPanel_1.Title = 'Laser 1';
            app.LGrid_1 = uigridlayout(app.LaserPanel_1);
            app.ConnectButton_1 = uibutton(app.LGrid_1, 'push');
            app.ConnectButton_1.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_1Pushed, true);
            app.ConnectButton_1.Tag = 'l11';
            app.ConnectButton_1.Text = 'Connect';
            app.Switch_1 = uiswitch(app.LGrid_1, 'slider');
            app.Switch_1.ValueChangedFcn = createCallbackFcn(app, @Switch_1ValueChanged, true);
            app.Switch_1.Tag = 'laser_1';
            app.nmLabel_1 = uilabel(app.LGrid_1);
            app.nmLabel_1.Tag = 'laser_1';
            app.nmLabel_1.Text = 'λ (nm)';
            app.PowermWEditFieldLabel_1 = uilabel(app.LGrid_1);
            app.PowermWEditFieldLabel_1.Tag = 'laser_1';
            app.PowermWEditFieldLabel_1.Text = 'Power (mW)';
            app.PowermWEditField_1 = uieditfield(app.LGrid_1, 'numeric');
            app.PowermWEditField_1.ValueChangedFcn = createCallbackFcn(app, @PowermWEditField_1ValueChanged, true);
            app.PowermWEditField_1.Tag = 'laser_1';
            app.ConnectedLampLabel_1 = uilabel(app.LGrid_1);
            app.ConnectedLampLabel_1.Tag = 'l11';
            app.ConnectedLampLabel_1.Text = 'Connected';
            app.LasingLampLabel_1 = uilabel(app.LGrid_1);
            app.LasingLampLabel_1.Tag = 'laser_1';
            app.LasingLampLabel_1.Text = 'Lasing';
            app.wavLabel_1 = uilabel(app.LGrid_1);
            app.wavLabel_1.Tag = 'laser_1';
            app.wavLabel_1.Text = 'wav';
            app.ConnectedLamp_1 = uilamp(app.LGrid_1);
            app.ConnectedLamp_1.Tag = 'l11';
            app.LasingLamp_1 = uilamp(app.LGrid_1);
            app.LasingLamp_1.Tag = 'laser_1';
            app.LaserPanel_2 = uipanel(app.GridLayout17);
            app.LaserPanel_2.Title = 'Laser 2';
            app.LGrid_2 = uigridlayout(app.LaserPanel_2);
            app.ConnectButton_2 = uibutton(app.LGrid_2, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Tag = 'l21';
            app.ConnectButton_2.Text = 'Connect';
            app.Switch_2 = uiswitch(app.LGrid_2, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tag = 'laser_2';
            app.nmLabel_2 = uilabel(app.LGrid_2);
            app.nmLabel_2.Tag = 'laser_2';
            app.nmLabel_2.Text = 'λ (nm)';
            app.PowermWEditField_2Label = uilabel(app.LGrid_2);
            app.PowermWEditField_2Label.Tag = 'laser_2';
            app.PowermWEditField_2Label.Text = 'Power (mW)';
            app.PowermWEditField_2 = uieditfield(app.LGrid_2, 'numeric');
            app.PowermWEditField_2.ValueChangedFcn = createCallbackFcn(app, @PowermWEditField_2ValueChanged, true);
            app.PowermWEditField_2.Tag = 'laser_2';
            app.ConnectedLamp_2Label = uilabel(app.LGrid_2);
            app.ConnectedLamp_2Label.Tag = 'l21';
            app.ConnectedLamp_2Label.Text = 'Connected';
            app.ConnectedLamp_2 = uilamp(app.LGrid_2);
            app.ConnectedLamp_2.Tag = 'l21';
            app.LasingLamp_2Label = uilabel(app.LGrid_2);
            app.LasingLamp_2Label.Tag = 'laser_2';
            app.LasingLamp_2Label.Text = 'Lasing';
            app.LasingLamp_2 = uilamp(app.LGrid_2);
            app.LasingLamp_2.Tag = 'laser_2';
            app.wavLabel_2 = uilabel(app.LGrid_2);
            app.wavLabel_2.Tag = 'laser_2';
            app.wavLabel_2.Text = 'wav';
            app.ManipulatorPanel = uipanel(app.GridLayout17);
            app.ManipulatorPanel.Title = 'Manipulator';
            app.GridLayout7 = uigridlayout(app.ManipulatorPanel);
            app.ConnectButton_8 = uibutton(app.GridLayout7, 'push');
            app.ConnectButton_8.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_8Pushed, true);
            app.ConnectButton_8.Tag = 'mn11';
            app.ConnectButton_8.Text = 'Connect';
            app.ConnectedLamp_8Label = uilabel(app.GridLayout7);
            app.ConnectedLamp_8Label.Tag = 'mn11';
            app.ConnectedLamp_8Label.Text = 'Connected';
            app.ConnectedLamp_8 = uilamp(app.GridLayout7);
            app.ConnectedLamp_8.Tag = 'mn11';
            app.PositionLabel = uilabel(app.GridLayout7);
            app.PositionLabel.Tag = 'mn1';
            app.PositionLabel.Text = 'Position (#)';
            app.cposLabel_2 = uilabel(app.GridLayout7);
            app.cposLabel_2.Tag = 'mn1';
            app.cposLabel_2.Text = 'cpos';
            app.UpdateButton_2 = uibutton(app.GridLayout7, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Tag = 'mn1';
            app.UpdateButton_2.Text = 'Update';
            app.GoToEditFieldLabel = uilabel(app.GridLayout7);
            app.GoToEditFieldLabel.Tag = 'mn1';
            app.GoToEditFieldLabel.Text = 'Go To (#)';
            app.GoToEditField = uieditfield(app.GridLayout7, 'numeric');
            app.GoToEditField.RoundFractionalValues = 'on';
            app.GoToEditField.ValueChangedFcn = createCallbackFcn(app, @GoToEditFieldValueChanged, true);
            app.GoToEditField.Tag = 'mn1';
            app.targetLabel_2 = uilabel(app.GridLayout7);
            app.targetLabel_2.Tag = 'mn1';
            app.targetLabel_2.Text = 'target';
            app.LaserPanel_6 = uipanel(app.GridLayout17);
            app.LaserPanel_6.Title = 'Laser Shutter 1';
            app.LGrid_6 = uigridlayout(app.LaserPanel_6);
            app.ConnectButton_9 = uibutton(app.LGrid_6, 'push');
            app.ConnectButton_9.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_9Pushed, true);
            app.ConnectButton_9.Tag = 'lash11';
            app.ConnectButton_9.Text = 'Connect';
            app.Switch_7 = uiswitch(app.LGrid_6, 'slider');
            app.Switch_7.Items = {'Close', 'Open'};
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            app.Switch_7.Tag = 'lash1';
            app.Switch_7.Value = 'Close';
            app.ConnectedLamp_9Label = uilabel(app.LGrid_6);
            app.ConnectedLamp_9Label.Tag = 'l11';
            app.ConnectedLamp_9Label.Text = 'Connected';
            app.ConnectedLamp_9 = uilamp(app.LGrid_6);
            app.ConnectedLamp_9.Tag = 'lash11';
            app.CloseAllButton = uibutton(app.LGrid_6, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Tag = 'lash1';
            app.CloseAllButton.Text = 'Close All';
            app.OpenedLamp_8Label = uilabel(app.LGrid_6);
            app.OpenedLamp_8Label.Tag = 'l11';
            app.OpenedLamp_8Label.Text = 'Opened';
            app.OpenedLamp = uilamp(app.LGrid_6);
            app.OpenedLamp.Tag = 'lash1';
            app.LaserPanel_9 = uipanel(app.GridLayout17);
            app.LaserPanel_9.Title = 'Laser Shutter 4';
            app.LGrid_9 = uigridlayout(app.LaserPanel_9);
            app.Switch_10 = uiswitch(app.LGrid_9, 'slider');
            app.Switch_10.Items = {'Close', 'Open'};
            app.Switch_10.ValueChangedFcn = createCallbackFcn(app, @Switch_10ValueChanged, true);
            app.Switch_10.Tag = 'lash1';
            app.Switch_10.Value = 'Close';
            app.OpenedLamp_4Label = uilabel(app.LGrid_9);
            app.OpenedLamp_4Label.Tag = 'l11';
            app.OpenedLamp_4Label.Text = 'Opened';
            app.OpenedLamp_4 = uilamp(app.LGrid_9);
            app.OpenedLamp_4.Tag = 'lash1';
            app.LaserPanel_12 = uipanel(app.GridLayout17);
            app.LaserPanel_12.Title = 'Laser Shutter 2';
            app.LGrid_12 = uigridlayout(app.LaserPanel_12);
            app.Switch_13 = uiswitch(app.LGrid_12, 'slider');
            app.Switch_13.Items = {'Close', 'Open'};
            app.Switch_13.ValueChangedFcn = createCallbackFcn(app, @Switch_13ValueChanged, true);
            app.Switch_13.Tag = 'lash1';
            app.Switch_13.Value = 'Close';
            app.OpenedLamp_7Label = uilabel(app.LGrid_12);
            app.OpenedLamp_7Label.Tag = 'l11';
            app.OpenedLamp_7Label.Text = 'Opened';
            app.OpenedLamp_7 = uilamp(app.LGrid_12);
            app.OpenedLamp_7.Tag = 'lash1';
            app.ConnectPM_4 = uibutton(app.GridLayout17, 'push');
            app.ConnectPM_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectPM_4ButtonPushed, true);
            app.ConnectPM_4.Text = 'CONNECT ALL';
            app.CryocoolerPanel = uipanel(app.GridLayout17);
            app.CryocoolerPanel.Title = 'Cryocooler';
            app.GridLayout15 = uigridlayout(app.CryocoolerPanel);
            app.ConnectButton_10 = uibutton(app.GridLayout15, 'push');
            app.ConnectButton_10.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_10Pushed, true);
            app.ConnectButton_10.Tag = 'cryo1';
            app.ConnectButton_10.Text = 'Connect';
            app.ConnectedLamp_11Label = uilabel(app.GridLayout15);
            app.ConnectedLamp_11Label.Tag = 'cryo1';
            app.ConnectedLamp_11Label.Text = 'Connected';
            app.ConnectedLamp_11 = uilamp(app.GridLayout15);
            app.ConnectedLamp_11.Tag = 'cryo1';
            app.ReadTempPowerKWButton = uibutton(app.GridLayout15, 'push');
            app.ReadTempPowerKWButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTempPowerKWButtonPushed, true);
            app.ReadTempPowerKWButton.Tag = 'cryo';
            app.ReadTempPowerKWButton.Text = 'Read Temp, Power (K,W)';
            app.tempKLabel = uilabel(app.GridLayout15);
            app.tempKLabel.Tag = 'cryo';
            app.tempKLabel.Text = 'tempK';
            app.SetTemperatureEditFieldLabel = uilabel(app.GridLayout15);
            app.SetTemperatureEditFieldLabel.Tag = 'cryo';
            app.SetTemperatureEditFieldLabel.Text = 'Set Temperature';
            app.SetTemperatureEditField = uieditfield(app.GridLayout15, 'numeric');
            app.SetTemperatureEditField.Limits = [40 500];
            app.SetTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @SetTemperatureEditFieldValueChanged, true);
            app.SetTemperatureEditField.Tag = 'cryo';
            app.SetTemperatureEditField.Value = 40;
            app.Switch_14 = uiswitch(app.GridLayout15, 'slider');
            app.Switch_14.ValueChangedFcn = createCallbackFcn(app, @Switch_14ValueChanged, true);
            app.Switch_14.Tag = 'cryo';
            app.ReadTSetKButton = uibutton(app.GridLayout15, 'push');
            app.ReadTSetKButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTSetKButtonPushed, true);
            app.ReadTSetKButton.Tag = 'cryo';
            app.ReadTSetKButton.Text = 'Read T-Set (K)';
            app.setKcurrentKLabel = uilabel(app.GridLayout15);
            app.setKcurrentKLabel.Tag = 'cryo';
            app.setKcurrentKLabel.Text = 'setK, current K';
            app.LaserPanel_4 = uipanel(app.GridLayout17);
            app.LaserPanel_4.Title = 'Laser 4';
            app.GridLayout16 = uigridlayout(app.LaserPanel_4);
            app.LGrid_4 = uigridlayout(app.GridLayout16);
            app.ConnectButton_4 = uibutton(app.LGrid_4, 'push');
            app.ConnectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_4Pushed, true);
            app.ConnectButton_4.Tag = 'l41';
            app.ConnectButton_4.Text = 'Connect';
            app.Switch_4 = uiswitch(app.LGrid_4, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Tag = 'laser_4';
            app.nmLabel_4 = uilabel(app.LGrid_4);
            app.nmLabel_4.Tag = 'laser_4';
            app.nmLabel_4.Text = 'λ (nm)';
            app.PowermWEditField_4Label = uilabel(app.LGrid_4);
            app.PowermWEditField_4Label.Tag = 'laser_4';
            app.PowermWEditField_4Label.Text = 'Power (mW)';
            app.PowermWEditField_4 = uieditfield(app.LGrid_4, 'numeric');
            app.PowermWEditField_4.ValueChangedFcn = createCallbackFcn(app, @PowermWEditField_4ValueChanged, true);
            app.PowermWEditField_4.Tag = 'laser_4';
            app.ConnectedLamp_4Label = uilabel(app.LGrid_4);
            app.ConnectedLamp_4Label.Tag = 'l41';
            app.ConnectedLamp_4Label.Text = 'Connected';
            app.ConnectedLamp_4 = uilamp(app.LGrid_4);
            app.ConnectedLamp_4.Tag = 'l41';
            app.LasingLamp_4Label = uilabel(app.LGrid_4);
            app.LasingLamp_4Label.Tag = 'laser_4';
            app.LasingLamp_4Label.Text = 'Lasing';
            app.LasingLamp_4 = uilamp(app.LGrid_4);
            app.LasingLamp_4.Tag = 'laser_4';
            app.wavLabel_4 = uilabel(app.LGrid_4);
            app.wavLabel_4.Tag = 'laser_4';
            app.wavLabel_4.Text = 'wav';
            app.LaserPanel_10 = uipanel(app.GridLayout17);
            app.LaserPanel_10.Title = 'Laser Shutter 5';
            app.LGrid_10 = uigridlayout(app.LaserPanel_10);
            app.Switch_11 = uiswitch(app.LGrid_10, 'slider');
            app.Switch_11.Items = {'Close', 'Open'};
            app.Switch_11.ValueChangedFcn = createCallbackFcn(app, @Switch_11ValueChanged, true);
            app.Switch_11.Tag = 'lash1';
            app.Switch_11.Value = 'Close';
            app.OpenedLamp_5Label = uilabel(app.LGrid_10);
            app.OpenedLamp_5Label.Tag = 'l11';
            app.OpenedLamp_5Label.Text = 'Opened';
            app.OpenedLamp_5 = uilamp(app.LGrid_10);
            app.OpenedLamp_5.Tag = 'lash1';
            app.LaserPanel_5 = uipanel(app.GridLayout17);
            app.LaserPanel_5.Title = 'Laser 5';
            app.LGrid_5 = uigridlayout(app.LaserPanel_5);
            app.ConnectButton_5 = uibutton(app.LGrid_5, 'push');
            app.ConnectButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_5Pushed, true);
            app.ConnectButton_5.Tag = 'l51';
            app.ConnectButton_5.Text = 'Connect';
            app.Switch_5 = uiswitch(app.LGrid_5, 'slider');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Tag = 'laser_5';
            app.nmLabel_5 = uilabel(app.LGrid_5);
            app.nmLabel_5.Tag = 'laser_5';
            app.nmLabel_5.Text = 'λ (nm)';
            app.PowermWEditField_5Label = uilabel(app.LGrid_5);
            app.PowermWEditField_5Label.Tag = 'laser_5';
            app.PowermWEditField_5Label.Text = 'Power (mW)';
            app.PowermWEditField_5 = uieditfield(app.LGrid_5, 'numeric');
            app.PowermWEditField_5.ValueChangedFcn = createCallbackFcn(app, @PowermWEditField_5ValueChanged, true);
            app.PowermWEditField_5.Tag = 'laser_5';
            app.ConnectedLamp_5Label = uilabel(app.LGrid_5);
            app.ConnectedLamp_5Label.Tag = 'l51';
            app.ConnectedLamp_5Label.Text = 'Connected';
            app.ConnectedLamp_5 = uilamp(app.LGrid_5);
            app.ConnectedLamp_5.Tag = 'l51';
            app.LasingLamp_5Label = uilabel(app.LGrid_5);
            app.LasingLamp_5Label.Tag = 'laser_5';
            app.LasingLamp_5Label.Text = 'Lasing';
            app.LasingLamp_5 = uilamp(app.LGrid_5);
            app.LasingLamp_5.Tag = 'laser_5';
            app.wavLabel_5 = uilabel(app.LGrid_5);
            app.wavLabel_5.Tag = 'laser_5';
            app.wavLabel_5.Text = 'wav';
            app.LaserPanel_14 = uipanel(app.GridLayout17);
            app.LaserPanel_14.Title = 'Laser 6';
            app.LGrid_14 = uigridlayout(app.LaserPanel_14);
            app.ConnectButton_11 = uibutton(app.LGrid_14, 'push');
            app.ConnectButton_11.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_11Pushed, true);
            app.ConnectButton_11.Tag = 'l61';
            app.ConnectButton_11.Text = 'Connect';
            app.Switch_16 = uiswitch(app.LGrid_14, 'slider');
            app.Switch_16.ValueChangedFcn = createCallbackFcn(app, @Switch_16ValueChanged, true);
            app.Switch_16.Tag = 'laser_6';
            app.nmLabel_6 = uilabel(app.LGrid_14);
            app.nmLabel_6.Tag = 'laser_6';
            app.nmLabel_6.Text = 'λ (nm)';
            app.PowermWEditField_6Label = uilabel(app.LGrid_14);
            app.PowermWEditField_6Label.Tag = 'laser_6';
            app.PowermWEditField_6Label.Text = 'Power (mW)';
            app.PowermWEditField_6 = uieditfield(app.LGrid_14, 'numeric');
            app.PowermWEditField_6.ValueChangedFcn = createCallbackFcn(app, @PowermWEditField_6ValueChanged, true);
            app.PowermWEditField_6.Tag = 'laser_6';
            app.ConnectedLamp_12Label = uilabel(app.LGrid_14);
            app.ConnectedLamp_12Label.Tag = 'l61';
            app.ConnectedLamp_12Label.Text = 'Connected';
            app.ConnectedLamp_12 = uilamp(app.LGrid_14);
            app.ConnectedLamp_12.Tag = 'l61';
            app.LasingLamp_6Label = uilabel(app.LGrid_14);
            app.LasingLamp_6Label.Tag = 'laser_6';
            app.LasingLamp_6Label.Text = 'Lasing';
            app.LasingLamp_6 = uilamp(app.LGrid_14);
            app.LasingLamp_6.Tag = 'laser_6';
            app.wavLabel_6 = uilabel(app.LGrid_14);
            app.wavLabel_6.Tag = 'laser_6';
            app.wavLabel_6.Text = 'wav';
            app.LaserPanel_13 = uipanel(app.GridLayout17);
            app.LaserPanel_13.Title = 'Laser Shutter 6';
            app.LGrid_13 = uigridlayout(app.LaserPanel_13);
            app.Switch_15 = uiswitch(app.LGrid_13, 'slider');
            app.Switch_15.Items = {'Close', 'Open'};
            app.Switch_15.ValueChangedFcn = createCallbackFcn(app, @Switch_15ValueChanged, true);
            app.Switch_15.Tag = 'lash1';
            app.Switch_15.Enable = 'off';
            app.Switch_15.Value = 'Close';
            app.OpenedLamp_8Label_2 = uilabel(app.LGrid_13);
            app.OpenedLamp_8Label_2.Tag = 'l11';
            app.OpenedLamp_8Label_2.Text = 'Opened';
            app.OpenedLamp_8 = uilamp(app.LGrid_13);
            app.OpenedLamp_8.Tag = 'lash1';
            app.LaserPanel_16 = uipanel(app.GridLayout17);
            app.LaserPanel_16.Title = 'Laser 7';
            app.LGrid_16 = uigridlayout(app.LaserPanel_16);
            app.ConnectButton_12 = uibutton(app.LGrid_16, 'push');
            app.ConnectButton_12.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_12Pushed, true);
            app.ConnectButton_12.Tag = 'l71';
            app.ConnectButton_12.Text = 'Connect';
            app.Switch_18 = uiswitch(app.LGrid_16, 'slider');
            app.Switch_18.ValueChangedFcn = createCallbackFcn(app, @Switch_18ValueChanged, true);
            app.Switch_18.Tag = 'laser_7';
            app.nmLabel_7 = uilabel(app.LGrid_16);
            app.nmLabel_7.Tag = 'laser_7';
            app.nmLabel_7.Text = 'λ (nm)';
            app.PowermWEditField_7Label = uilabel(app.LGrid_16);
            app.PowermWEditField_7Label.Tag = 'laser_7';
            app.PowermWEditField_7Label.Text = 'Power (mW)';
            app.PowermWEditField_7 = uieditfield(app.LGrid_16, 'numeric');
            app.PowermWEditField_7.ValueChangedFcn = createCallbackFcn(app, @PowermWEditField_7ValueChanged, true);
            app.PowermWEditField_7.Tag = 'laser_7';
            app.ConnectedLamp_13Label = uilabel(app.LGrid_16);
            app.ConnectedLamp_13Label.Tag = 'l71';
            app.ConnectedLamp_13Label.Text = 'Connected';
            app.ConnectedLamp_13 = uilamp(app.LGrid_16);
            app.ConnectedLamp_13.Tag = 'l71';
            app.LasingLamp_7Label = uilabel(app.LGrid_16);
            app.LasingLamp_7Label.Tag = 'laser_7';
            app.LasingLamp_7Label.Text = 'Lasing';
            app.LasingLamp_7 = uilamp(app.LGrid_16);
            app.LasingLamp_7.Tag = 'laser_7';
            app.wavLabel_7 = uilabel(app.LGrid_16);
            app.wavLabel_7.Tag = 'laser_7';
            app.wavLabel_7.Text = 'wav';
            app.LaserPanel_15 = uipanel(app.GridLayout17);
            app.LaserPanel_15.Title = 'Laser Shutter 7';
            app.LGrid_15 = uigridlayout(app.LaserPanel_15);
            app.Switch_17 = uiswitch(app.LGrid_15, 'slider');
            app.Switch_17.Items = {'Close', 'Open'};
            app.Switch_17.ValueChangedFcn = createCallbackFcn(app, @Switch_17ValueChanged, true);
            app.Switch_17.Tag = 'lash1';
            app.Switch_17.Enable = 'off';
            app.Switch_17.Value = 'Close';
            app.OpenedLamp_9Label = uilabel(app.LGrid_15);
            app.OpenedLamp_9Label.Tag = 'l11';
            app.OpenedLamp_9Label.Text = 'Opened';
            app.OpenedLamp_9 = uilamp(app.LGrid_15);
            app.OpenedLamp_9.Tag = 'lash1';
            app.LaserPanel_3 = uipanel(app.GridLayout17);
            app.LaserPanel_3.Title = 'Laser 3';
            app.LGrid_3 = uigridlayout(app.LaserPanel_3);
            app.ConnectButton_3 = uibutton(app.LGrid_3, 'push');
            app.ConnectButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_3Pushed, true);
            app.ConnectButton_3.Tag = 'l31';
            app.ConnectButton_3.Text = 'Connect';
            app.Switch_3 = uiswitch(app.LGrid_3, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Tag = 'laser_3';
            app.nmLabel_3 = uilabel(app.LGrid_3);
            app.nmLabel_3.Tag = 'laser_3';
            app.nmLabel_3.Text = 'λ (nm)';
            app.PowermWEditField_3Label = uilabel(app.LGrid_3);
            app.PowermWEditField_3Label.Tag = 'laser_3';
            app.PowermWEditField_3Label.Text = 'Power (mW)';
            app.PowermWEditField_3 = uieditfield(app.LGrid_3, 'numeric');
            app.PowermWEditField_3.ValueChangedFcn = createCallbackFcn(app, @PowermWEditField_3ValueChanged, true);
            app.PowermWEditField_3.Tag = 'laser_3';
            app.ConnectedLamp_3Label = uilabel(app.LGrid_3);
            app.ConnectedLamp_3Label.Tag = 'l31';
            app.ConnectedLamp_3Label.Text = 'Connected';
            app.ConnectedLamp_3 = uilamp(app.LGrid_3);
            app.ConnectedLamp_3.Tag = 'l31';
            app.LasingLamp_3Label = uilabel(app.LGrid_3);
            app.LasingLamp_3Label.Tag = 'laser_3';
            app.LasingLamp_3Label.Text = 'Lasing';
            app.LasingLamp_3 = uilamp(app.LGrid_3);
            app.LasingLamp_3.Tag = 'laser_3';
            app.wavLabel_3 = uilabel(app.LGrid_3);
            app.wavLabel_3.Tag = 'laser_3';
            app.wavLabel_3.Text = 'wav';
            app.LaserPanel_11 = uipanel(app.GridLayout17);
            app.LaserPanel_11.Title = 'Laser Shutter 3';
            app.LGrid_11 = uigridlayout(app.LaserPanel_11);
            app.Switch_12 = uiswitch(app.LGrid_11, 'slider');
            app.Switch_12.Items = {'Close', 'Open'};
            app.Switch_12.ValueChangedFcn = createCallbackFcn(app, @Switch_12ValueChanged, true);
            app.Switch_12.Tag = 'lash1';
            app.Switch_12.Value = 'Close';
            app.OpenedLamp_6Label = uilabel(app.LGrid_11);
            app.OpenedLamp_6Label.Tag = 'l11';
            app.OpenedLamp_6Label.Text = 'Opened';
            app.OpenedLamp_6 = uilamp(app.LGrid_11);
            app.OpenedLamp_6.Tag = 'lash1';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_2024_3_22

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end